classdef Register < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Reg3DV400daVinciUIFigure       matlab.ui.Figure
        Menu_File                      matlab.ui.container.Menu
        Menu_LoadImage                 matlab.ui.container.Menu
        Menu_LoadTime                  matlab.ui.container.Menu
        Menu_SaveImageAs               matlab.ui.container.Menu
        Menu_SaveTimeAs                matlab.ui.container.Menu
        Menu_SaveMovieAs               matlab.ui.container.Menu
        Menu_Quit                      matlab.ui.container.Menu
        Menu_Image                     matlab.ui.container.Menu
        Menu_LightContrast             matlab.ui.container.Menu
        Menu_ZProjection               matlab.ui.container.Menu
        Menu_MaxZProjection            matlab.ui.container.Menu
        Menu_MinZProjection            matlab.ui.container.Menu
        Menu_MedianZProjection         matlab.ui.container.Menu
        Menu_MeanZProjection           matlab.ui.container.Menu
        Menu_ChannelAlignment          matlab.ui.container.Menu
        Menu_Comments                  matlab.ui.container.Menu
        Menu_TimeStamp                 matlab.ui.container.Menu
        Menu_ScaleBar                  matlab.ui.container.Menu
        Menu_MetaMarker                matlab.ui.container.Menu
        Menu_Crop3D                    matlab.ui.container.Menu
        Menu_CropXY                    matlab.ui.container.Menu
        Menu_CropZ                     matlab.ui.container.Menu
        Menu_CropT                     matlab.ui.container.Menu
        Menu_Flip                      matlab.ui.container.Menu
        Menu_FlipHorizontal            matlab.ui.container.Menu
        Menu_FlipVertical              matlab.ui.container.Menu
        Menu_Rotate                    matlab.ui.container.Menu
        Menu_RotateClockwise90         matlab.ui.container.Menu
        Menu_RotateCounterclockwise90  matlab.ui.container.Menu
        Menu_Options                   matlab.ui.container.Menu
        Menu_RunModal                  matlab.ui.container.Menu
        Menu_RunModalDistributed       matlab.ui.container.Menu
        Menu_RunModalExclusive         matlab.ui.container.Menu
        Menu_Register                  matlab.ui.container.Menu
        Menu_RegOptions                matlab.ui.container.Menu
        Menu_RegRange                  matlab.ui.container.Menu
        Menu_RegEvaluate               matlab.ui.container.Menu
        Menu_SaveParameters            matlab.ui.container.Menu
        Menu_LoadParameters            matlab.ui.container.Menu
        Menu_Animation                 matlab.ui.container.Menu
        Menu_1F                        matlab.ui.container.Menu
        Menu_5F                        matlab.ui.container.Menu
        Menu_15F                       matlab.ui.container.Menu
        Menu_PlayMode                  matlab.ui.container.Menu
        Menu_Direct                    matlab.ui.container.Menu
        Menu_Loop                      matlab.ui.container.Menu
        Menu_Advanced                  matlab.ui.container.Menu
        Menu_ResourceManager           matlab.ui.container.Menu
        Menu_CellSegmentor             matlab.ui.container.Menu
        Menu_SignalExtractor           matlab.ui.container.Menu
        Menu_Help                      matlab.ui.container.Menu
        Menu_About                     matlab.ui.container.Menu
        Menu_Documents                 matlab.ui.container.Menu
        GridLayout_RegView             matlab.ui.container.GridLayout
        GridLayout_RegBody             matlab.ui.container.GridLayout
        Panel_Viewer                   matlab.ui.container.Panel
        GridLayout_ImageViewer         matlab.ui.container.GridLayout
        Label_ImageInfo                matlab.ui.control.Label
        Slider_Time                    matlab.ui.control.Slider
        Label                          matlab.ui.control.Label
        GridLayout_ViewBody            matlab.ui.container.GridLayout
        GridLayout_MainWin             matlab.ui.container.GridLayout
        GridLayout_OuterPB             matlab.ui.container.GridLayout
        GridLayout_ProgressBar         matlab.ui.container.GridLayout
        Label_ProgressBar              matlab.ui.control.Label
        GridLayout_SidebarRegion       matlab.ui.container.GridLayout
        Button_HistoryManager          matlab.ui.control.Button
        GridLayout6                    matlab.ui.container.GridLayout
        Image_HMIndicator              matlab.ui.control.Image
        Image_Update                   matlab.ui.control.Image
        Spinner_Slice                  matlab.ui.control.Spinner
        GridLayout                     matlab.ui.container.GridLayout
        Spinner_Time                   matlab.ui.control.Spinner
        Slider_Slice                   matlab.ui.control.Slider
        Label_2                        matlab.ui.control.Label
        Panel_Control                  matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout4                    matlab.ui.container.GridLayout
        DropDown_Source                matlab.ui.control.DropDown
        Label_19                       matlab.ui.control.Label
        Button_Run                     matlab.ui.control.StateButton
        GridLayout3                    matlab.ui.container.GridLayout
        Label_20                       matlab.ui.control.Label
        TextArea_Log                   matlab.ui.control.TextArea
        Switch_Log                     matlab.ui.control.RockerSwitch
        GridLayout_HistoryManager      matlab.ui.container.GridLayout
        Panel_StorageViewer            matlab.ui.container.Panel
        GridLayout_StorageViewer       matlab.ui.container.GridLayout
        Panel_OperationsViewer         matlab.ui.container.Panel
        GridLayout_OperationViewer     matlab.ui.container.GridLayout
        Tree_Operations                matlab.ui.container.Tree
        Panel_OperationSnapshot        matlab.ui.container.Panel
        GridLayout_OperationSnapshot   matlab.ui.container.GridLayout
        TextArea_OperationSnapshot     matlab.ui.control.TextArea
        ContextMenu_Player             matlab.ui.container.ContextMenu
        Menu_ROI                       matlab.ui.container.Menu
        Menu_RectangleROI              matlab.ui.container.Menu
        Menu_CircleROI                 matlab.ui.container.Menu
        Menu_PolygonROI                matlab.ui.container.Menu
        Menu_FreehandROI               matlab.ui.container.Menu
        Menu_Channel                   matlab.ui.container.Menu
        Menu_DisplayMode               matlab.ui.container.Menu
        Menu_View2D                    matlab.ui.container.Menu
        Menu_View3D                    matlab.ui.container.Menu
        ContextMenu_Controller         matlab.ui.container.ContextMenu
        Menu_ExportLog                 matlab.ui.container.Menu
        Menu_ClearLog                  matlab.ui.container.Menu
        Menu_AutoClearLog              matlab.ui.container.Menu
        ContextMenu_Operation          matlab.ui.container.ContextMenu
        Menu_ActivateNode              matlab.ui.container.Menu
        Menu_DeleteNode                matlab.ui.container.Menu
        ContextMenu_PanelFolding       matlab.ui.container.ContextMenu
        Menu_PanelExpand               matlab.ui.container.Menu
        Menu_PanelCollapse             matlab.ui.container.Menu
    end

    properties (Constant)
        VER = "4.0.0"
        RELEASE_CODE = "da Vinci"
        RELEASE_STATUS = "release"
        PROFILE_FOLDER = ['Reg3D', filesep]
    end

    properties(Constant, Hidden)
        SUBAPP_SNAME = ["BC", "CC", "CS", "EV", "FS", "ME", "RM", "RS", ...
            "SB", "SE"]
        SUBAPP_SNAME_DATA_RELATED = ["BC", "CC", "CS", "EV", "FS", "ME", ...
            "RS", "SB", "SE"]

        INIT_TIME_CONST = [0.3, 0.45, 0.95, 1];  % experience value

        DAEMON_TIMER_PERIOD = 5             % second, no more than 60
        PLAYER_TIMER_PERIOD = 1             % second
        PROGRESSBAR_TIMER_PERIOD = 0.01     % second

        LAYOUT_WIDTH_INIT = 760
        LAYOUT_HEIGHT_INIT = 760
        RIGHT_SIDEBAR_WIDTH = 240
    end

    properties (Access = public)
        % dynamic components
        Menu_MergeSource            matlab.ui.container.Menu
        Menu_MergeRB                matlab.ui.container.Menu
        Menu_MergeGR                matlab.ui.container.Menu
        Menu_Red                    matlab.ui.container.Menu
        Menu_Green                  matlab.ui.container.Menu
        Menu_Red_Green              matlab.ui.container.Menu
        Menu_CellManager            matlab.ui.container.Menu
        Menu_AddCell                matlab.ui.container.Menu
        Menu_RefreshLabel           matlab.ui.container.Menu
        Menu_AnomalyDetection       matlab.ui.container.Menu
        UIAxes_Slice                matlab.ui.control.UIAxes
        UIViewer_Vol                images.ui.graphics3d.Viewer3D
    end

    % inpersistent data, reset after loading
    properties (Access = private)
        % data source
        img_cur             % 1-by-1 regmov object, indicate current movie
        mask_reg            % m-by-n-by-p regmask object, with mROI mask
        refvol              % 1-by-1 regtmpl object
        opt_reg             % 1-by-1 regopt object
        opt_seg             % 1-by-1 segopt object
        opt_sig             % 1-by-1 sigopt object
        opt_crop            % 1-by-1 struct, the current ROI, field with xy, z and f(frame)
        opt_locked          % 1-by-p table, flag for registration options setting done
        img_zproj           % 1-by-1 struct, with field {cur, ref}, the z-projected images
        fixdef              % 1-by-1 struct, with the fixed definition and channel
        frames_reg          % 1-by-2 string array, the registration frames, [auto, manual]

        % viewing/operation position indicator/information
        fidx_cur            % 1-by-1 positive integer, the current frame index
        sidx_cur            % 1-by-1 positive integer, the current slice index
        cidx_cur            % 1-by-c logical array, the current channel index
        simg_cur            % m-by-n-by-3 array, the current slice image(transformed) for viewing
        vimg_cur            % m-by-n-by-p-by-3 array, the current volume image(transformed) for viewing
        c_order             % 1-by-c string, the channels order
        graphics_opts       % 1-by-1 struct, with field {r, b, c, ga}, the viewer options
        movie_config        % 1-by-1 struct, the exported movie configuration
        scroll_ylim         % 1-by-2 double, the y position limitation of scroll image
        pbx_old             % 1-by-1 double, the old progress value

        % viewing/playing mode
        pcolor              % 1-by-1 struct with 3-by-3 pseudo RGB color definition, {normal, fused}
        mode_zproj          % 1-by-1 string, the flag for current viewing mode, ["none", "max","min","mean","median"]
        mode_merge          % 1-by-1 string, the mode for merge viewing， ["NORMAL", "FUSED", ""]
        mode_loop           % 1-by-1 string, the viewing loop mode, ["one-step", "loop"]
        mode_dview          % 1-by-1 string, the viewer display mode, ["2D", "3D"]
        channel_locker      % 1-by-1 logical, it will be true in channel aligned mode
        flag_playing        % 1-by-1 logical, flag for movie play right now
        fps                 % 1-by-1 struct, the play speed setting, {base, fold} 

        % image tool handles
        hdle_image          % 1-by-1 image object
        hdle_volume         % 1-by-1 Volume object
        hdle_ROI            % 1-by-1 images.roi object
        hdle_sArea          % 1-by-s cell, each item is images.roi object, cropped segment area
        hdle_axtb           % 1-by-1 AxesToolBar object, control the 2D view
        text_time           % 1-by-1 struct, the time text info, {handle, text, ismoving}
        text_meta           % 1-by-1 struct, the meta text info struct, {handle, text, ismoving}
        sbar                % 1-by-1 scalebar object

        % app configurations
        filename            % 1-by-1 string, record the processing file name
        path_save           % 1-by-1 string, the file saving path
        flag_server         % 1-by-1 logical, the flag for estimating if running on server
        region              % 1-by-1 struct, the region setting, {plat, lang, chmap}

        % subapp handles
        BC_handle           % the brightness and contrast setting handle
        CC_handle           % the channel alignment handle
        CS_handle           % the cell segmentor handle
        EV_handle           % the evaluation handle
        FS_handle           % the frames selecter handle
        ME_handle           % the movie exporter handle
        RM_handle           % the resource manager handle
        RS_handle           % the registration setting handle
        SB_handle           % the scale bar setting handle
        SE_handle           % the signal estimator handle

        % system management tools/flags
        kernel              % 1-by-1 Registration-Controller object    
        ohmgr               % 1-by-1 operation history manager object
        player_timer        % 1-by-1 timer for viewing animation
        progress_timer      % 1-by-1 timer for progress bar running indeterminately
        regdaemon_timer     % 1-by-1 timer for app management
        loaded_flag         % 1-by-1 logical, flag for data loading success
        masked_flag         % 1-by-1 logical, flag for data masked
        distrib_flag        % 1-by-1 logical, the distribution mode flag
        himgr_flag          % 1-by-1 logical, flag for history manager panel enabled
        auto_clear_log_flag % 1-by-1 logical, flag for log auto clear
        viewer_locker       % 1-by-1 logical, locker for imaging view ability
        recycle_old_status  % 1-by-1 string, record the recycle status before running loading
        sysmsg              % 1-by-3 string array, record system messages history, [cmd, sfx, time]
        opt_app             % 1-by-1 struct with app basic options
        opt_update          % 1-by-1 struct, the options for update
    end

   %=================== INTERFACE FOR SUBAPP ============================
   methods(Access = {?BCAdjuster, ?BCAdjuster_old})
       function SetBCG(app, params)
            app.graphics_opts = params;

            % refresh viewer for real time applying
            RefreshViewer(app);
        end

        function params = GetBCG(app)
            params = app.graphics_opts;
        end

        function img = GetCurrentImg(app)
            switch app.mode_dview
                case "2D"
                    img = GetSliceImgAt(app);
                case "3D"
                    img = GetVolumeImgAt(app);
                otherwise
            end
        end
   end

   methods(Access = {?OCRegSetter, ?TCRegSetter})
       function SetRegOptions(app, regopt_, fixdef_, locked_)
            app.opt_reg = regopt_;
            app.fixdef = fixdef_;
            app.opt_locked = locked_;
            app.refvol = regtmpl(app.img_cur, app.fixdef);
        end

        function [regopt_, fixdef_, locked_] = GetRegOptions(app)
            regopt_ = app.opt_reg;
            fixdef_ = app.fixdef;
            locked_ = app.opt_locked;
        end
   end

   methods(Access = ?CellSegmentor)
       function SetSegOptions(app, segopt_)
           arguments
               app
               segopt_  (1,1)   segopt
           end

           app.opt_seg = segopt_;
       end

       function r = GetSegOptions(app)
           r = app.opt_seg;
       end
       
       function r = GetVolOptions(app)
           r = struct("width",  app.img_cur.MetaData.width, ...
                      "height", app.img_cur.MetaData.height, ...
                      "slices", app.img_cur.MetaData.slices, ...
                      "xRes",   app.img_cur.MetaData.xRes, ...
                      "yRes",   app.img_cur.MetaData.yRes, ...
                      "zRes",   app.img_cur.MetaData.zRes);

           r = struct2table(r);
       end

       function z = GetCurrentSliceIndex(app)
           z = app.sidx_cur;
       end

       function SetBoundingBoxes(app, n)
           arguments
               app
               n    (1,1)   double  {mustBePositive, mustBeInteger} = 1
           end

           if ~isempty(app.opt_seg.bdbox), app.opt_seg.bdbox = []; end

           % draw rectangle segmentation region
           for k = 1:n
               app.hdle_sArea{k} = drawrectangle(app.UIAxes_Slice, ...
                   "Label", sprintf("%s:%d", app.region.chmap.SEGMENTATION_AREA, k), ...
                   "Color","g", "FaceAlpha",0.2, "Deletable",true, ...
                   "UserData", k);

               addlistener(app.hdle_sArea{k}, "ROIMoved", @app.ModifySegmentArea);
               bdbox = [ceil(app.hdle_sArea{k}.Vertices(1,:)+0.5), 1, ...
                   ceil(app.hdle_sArea{k}.Vertices(3,1)-app.hdle_sArea{k}.Vertices(2,1)-1), ...
                   ceil(app.hdle_sArea{k}.Vertices(2,2)-app.hdle_sArea{k}.Vertices(1,2)-1), ...
                   app.img_cur.MetaData.slices-1];
               app.opt_seg.bdbox = [app.opt_seg.bdbox; bdbox];
           end
       end

       function RemoveBoundingBoxes(app)
           % free and set as empty
           if ~isempty(app.hdle_sArea)
               cellfun(@delete, app.hdle_sArea);
           end

           app.hdle_sArea = {};

           app.opt_seg.bdbox = [];
       end

       function SetBoundingBoxesState(app, state)
           arguments
               app
               state    string  {mustBeMember(state, ["on", "off"])} = "on"
           end

           switch state
               case "on"
                   for k = 1:numel(app.hdle_sArea)
                       if isvalid(app.hdle_sArea{k})
                           app.hdle_sArea{k}.Label = ...
                               sprintf("%s:%d", app.region.chmap.SEGMENTATION_AREA, k);
                           app.hdle_sArea{k}.Color = "g";
                       end
                   end
               case "off"
                   for k = 1:numel(app.hdle_sArea)
                       if isvalid(app.hdle_sArea{k})
                           app.hdle_sArea{k}.Label = ...
                               sprintf("%s:%d", app.region.chmap.SEGMENTATION_AREA_LOCK, k);
                           app.hdle_sArea{k}.Color = "y";
                       end
                   end
               otherwise
           end
       end

       function SetMaskVol(app, mask)
           app.mask_reg = regmask.recover(mask, size(app.refvol.RefVol));
           app.masked_flag = true;
           app.Menu_SignalExtractor.Enable = "on";
       end

       function AddCellManagerMenu(app)
           AppendCellManagerMenu(app);
       end

       function DeleteCellManagerMenu(app)
           RemoveCellManagerMenu(app);
       end

       function SetSigEstimatorState(app, state)
           arguments
               app
               state    string  {mustBeMember(state, ["on", "off"])} = "on"
           end

           switch state
               case "on"
                   % enable the estimator menu
                   app.Menu_SignalExtractor.Enable = "on";
               case "off"
                   % close estimator
                   CloseSubApp(app, "SE");

                   % disable the estimator menu
                   app.Menu_SignalExtractor.Enable = "off";
               otherwise
           end
       end

       function AppendSegmentHistory(app, ncbptr)
           arguments
               app  
               ncbptr   (1,1)   NuclearCtrlBot 
           end

           % !!! add history node
           [args, others] = app.TakeSystemBasicSnap();
           app.ohmgr.AddNode(constdef.OP_SEGMENT, args, ...
               "Data",app.img_cur, "Segmentor",ncbptr, "Others",others);
       end
   end

   methods(Access = {?OCRegSetter, ?TCRegSetter, ?CellSegmentor})
       function refvol = GetRefVol(app)
           refvol = regtmpl(app.img_cur, app.fixdef);
       end
   end

   methods(Access = {?scalebar, ?ScaleBar})
       function SetScaleBar(app, scale, bar, font)
            % update current image resolution
            if ~isempty(app.img_cur)
                app.img_cur.MetaData.xRes = scale.xr;
                app.img_cur.MetaData.yRes = scale.yr;
                app.img_cur.MetaData.zRes = scale.zr;
            end
            
            % set scale bar information
            app.sbar.Scale = scale;
            app.sbar.Bar = bar;
            app.sbar.Font = font;

            % refresh scalebar for real time applying
            app.sbar.clear();
            app.sbar.draw();
        end

        function sbar = GetScaleBar(app)
            sbar = app.sbar;
        end
   end

   methods(Access = ?SignalEstimator)
       function SetSigOptions(app, sigopt_)
           arguments
               app
               sigopt_  (1,1)   sigopt
           end

           app.opt_sig = sigopt_;
       end

       function r = GetSigOptions(app)
           r = app.opt_sig;
       end

       function r = GetSignalChannel(app)
           r = app.opt_reg.Options.FC;
       end

       function r = GetCurrentSource(app)
           r = app.img_cur;
       end

       function HighlightROI(app, r)
           % This function highlight the ROI with r marked
           arguments
               app
               r    (1,:)   double {mustBePositive, mustBeInteger}
           end

           % call cell segmentor subfunction
           if ~isempty(app.CS_handle) && isvalid(app.CS_handle)
               app.CS_handle.UpdateHighlights(r);
               app.CS_handle.UpdateMultiplyROIs(app.sidx_cur);
           end
       end

       function RenameROI(app, ridx, rname)
           arguments
               app
               ridx (1,1)   double {mustBePositive, mustBeInteger}
               rname(1,1)   string
           end

           app.mask_reg.MaskLabel(ridx) = rname;
       end
   end

   methods(Access = {?SignalEstimator, ?CellSegmentor})
       function r = GetMask(app)
           if app.mask_reg == true
               r = app.mask_reg;
           else
               r = [];
           end
       end
   end

   methods(Access = ?Evaluator)
       function tform = GetTransformation(app)
           tform = app.img_cur.Transformation;
       end

       function [mov_raw, mov_align] = GetMoviePair(app)
           % TODO: decrease memory use
           rpt = app.ohmgr.CurrentRSPoint;
           rpt_pre = app.ohmgr.GetpreviousNodeBefore();

           if ~isempty(rpt)
               switch rpt.Operator
                   case constdef.OP_REGISTER
                       mov_align = squeeze(rpt.getData().Movie(:,:,app.cidx_cur,:,:));
                       mov_raw = squeeze(rpt_pre.getData().Movie(:,:,app.cidx_cur,:,:));
                   otherwise
                       mov_raw = [];
                       mov_align = [];
               end
           end
       end

       function r = GetTemplate(app)
           r = app.refvol.RefVol;
       end
   end

   methods(Access = {?Evaluator, ?BCAdjuster, ?BCAdjuster_old})
       function r = GetCurrentChannel(app)
           r = app.c_order(app.cidx_cur);
       end
   end

   methods(Access = ?FrameSelecter)
       function r = GetCurrentFrameIndex(app)
           r = app.fidx_cur;
       end

       function SetFrames(app, indices)
           app.opt_crop.f = indices;
       end
   end

   methods(Access = {?MovieExporter, ?SignalEstimator})
       function r = GetFileName(app)
           r = string(app.filename);
       end
   end

   methods(Access = ?ChannelCorrector)
       function SetChannelAlignmentView(app, shift)
           UpdateCurrentSlice(app, shift);
           DrawCurrentSlice(app);
       end

       function SetChannelAlignment(app, shift)
           if any(shift ~= 0)
               selection = uiconfirm(app.Reg3DV400daVinciUIFigure,...
                   app.region.chmap.IRREVERSIBLE_CA,...
                   app.region.chmap.WARNING, "Icon","warning");
               if selection ~= "OK"
                   return;
               else
                   PanelWaiting(app);

                   % apply channel shift on data
                   RA = imref3d([app.img_cur.MetaData.height, ...
                       app.img_cur.MetaData.width, ...
                       app.img_cur.MetaData.slices], ...
                       app.img_cur.MetaData.xRes, ...
                       app.img_cur.MetaData.yRes, ...
                       app.img_cur.MetaData.zRes);
                   rot = [cosd(shift(3)), sind(shift(3)), 0; ...
                       -sind(shift(3)), cosd(shift(3)), 0; ...
                       0, 0, 1];
                   tform = rigidtform3d(rot, [shift(1:2), 0]);
                   [~, cidx] = ismember(app.opt_reg.Options.FC, app.img_cur.MetaData.cOrder);
                   fill_val = prctile(app.img_cur.Movie(:,:,cidx,:,1), 10, "all");
                   t_span = sprintf("1:%d",app.img_cur.MetaData.frames);

                   bar = uiprogressdlg(app.Reg3DV400daVinciUIFigure,...
                       "Title",app.region.chmap.INFORMATION, ...
                       "Message",app.region.chmap.CHANNEL_ALIGNING,...
                       "Indeterminate","on", "Icon","info");
                   drawnow;

                   parpool("Threads");

                   % align functional channel to structured channel

                   tmpmov = grv(app.img_cur, ["none", t_span], ...
                       app.opt_reg.Options.FC);

                   parfor t = 1:app.img_cur.MetaData.frames
                       tmp_data = tmpmov(:,:,:,t);
                       tmpmov(:,:,:,t) =  imwarp(tmp_data, RA, tform,...
                           "linear","FillValues",fill_val, "OutputView",RA);
                   end

                   srv(app.img_cur, tmpmov, ["none", t_span], ...
                       app.opt_reg.Options.FC);

                   delete(gcp("nocreate"));

                   close(bar);

                   % Synchronize z projection
                   SourceZProjection(app, [], app.mode_zproj);

                   PanelRecovered(app);

                   % RefreshViewer for viewing recovery
                   RefreshViewer(app);
               end
           end
       end
   end

   methods(Access = ?MovieExporter)
       function r = GetMovieConfig(app)
           r = app.movie_config;
       end
       function SetMovieConfig(app, x)
           arguments
               app
               x    (1,1)   struct
           end

           app.movie_config = x;
       end
   end

   methods(Access = ?ResourceManager)
       function r = GetEngineStatus(app)
           r = app.kernel.State;
       end

       function r = GetEngineInfo(app)
           r = app.kernel.SysInfo;
       end

       function SetNWorkersProtected(app, x)
           arguments
               app
               x   (1,2)   double  {mustBeNonnegative, mustBeInteger}
           end
           app.kernel.NWorkersProtected = x;
       end
   end

   methods(Access = ?ResourceManager)
       function r = GetAppProfile(app)
           r = app.opt_app;
       end

       function r = GetAppProfileFullPath(app)
           pfpath = [userpath, filesep, app.PROFILE_FOLDER];
           r = fullfile(pfpath, ".profile.xml");
       end

       function SetAppProfile(app, x)
           arguments
               app
               x    (1,1)   struct
           end

           app.opt_app = x;

           % apply appearance options
           UpdateAppProfiles(app);
       end
   end

   methods(Access = ?TaskManager)
       function UpdateRegParams(app, task_)
           arguments
               app
               task_ (1,1)  task
           end

           % update regopts by information in task
           app.opt_reg = task_.RegOptions;

           switch app.opt_reg.Algorithm
               case "LTREG"
                   if app.opt_reg.Options.RegChain.isValid
                       % update fixdef
                       app.fixdef = app.opt_reg.Options.RegChain.Template.FixDef;
                   end
               otherwise
           end

           if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
               app.RS_handle.Refresh(app);
           end
       end
   end

   methods (Access = public)
        function info = GetImgInfo(app)
            info = app.img_cur.MetaData;
        end

        function [lang, mapping] = GetLangsInfo(app)
            lang = app.region.lang;
            mapping = app.region.chmap;
        end

        function stl = GetAppStyle(app)
            stl = app.opt_app.Style;
        end

        function SendMessage(app, id, timeStamp)
            arguments
                app
                id          (1,2)   string
                timeStamp   (1,1)   logical = true
            end
            
            GenSysMsg(app, id, true, timeStamp);
        end

        function SetProgressBar(app, x, iterinf)
            arguments
                app
                x       (1,1)   double  {mustBeInRange(x, 0, 1)} = 0
                iterinf (1,1)   logical = false
            end

            if ~iterinf
                stop(app.progress_timer);
                app.pbx_old = x;
                len = app.Reg3DV400daVinciUIFigure.Position(3) - (60*2+10*4+7*2);
                app.GridLayout_ProgressBar.ColumnWidth = {0, round(x*len)};
                drawnow
            else
                if x == 0
                    start(app.progress_timer);
                end
            end
        end
    end
    
    %=========================== APP METHODS =============================
    methods (Access = private)
        %====================== INIT COMPONENTS ==========================
        
        function InitSysOptions(app)
            % load the opt_app, if not exist, use default
            app.opt_app = GetInitProfiles(app);
            app.opt_reg = regopt("TCREG", "global");
            app.opt_seg = segopt();
            app.opt_sig = sigopt("MovingQuantile", "Constant");
            app.opt_locked = table('Size', [1,4], ...
                'VariableTypes', repmat({'logical'},1,4), ...
                'VariableNames', {'Global','Local','MGlobal','LTGlobal'});
            app.opt_update = struct("is_update",false,...
                "repository","","auto_clean",false);
            app.auto_clear_log_flag = false;
        end

        function [opt, others] = TakeSystemBasicSnap(app)
            opt = struct(constdef.OP_CROP,      app.opt_crop, ...
                         constdef.OP_LOAD,      [], ...
                         constdef.OP_REGISTER,  app.opt_reg, ...
                         constdef.OP_SEGMENT,   app.opt_seg);

            others = struct("text_time",    app.text_time, ...
                            "text_meta",    app.text_meta, ...
                            "fixdef",       app.fixdef, ...
                            "frames_reg",   app.frames_reg, ...
                            "mask_reg",     app.mask_reg);
        end

        function RestoreSystemBy(app, nd)
            % unlock the display locker
            app.viewer_locker = true;

            %% restore data and basic options
            % update current image source
            app.img_cur = nd.data;

            % restore basic options
            app.opt_crop = nd.arg.(constdef.OP_CROP);
            app.opt_reg = nd.arg.(constdef.OP_REGISTER);
            app.opt_seg = nd.arg.(constdef.OP_SEGMENT);

            %% synchronize other variables
            % restore variables
            app.text_time = nd.others.text_time;
            app.text_meta = nd.others.text_meta;
            app.fixdef = nd.others.fixdef;
            app.frames_reg = nd.others.frames_reg;
            app.mask_reg = nd.others.mask_reg;

            % reset variables
            app.fidx_cur = 1;
            app.sidx_cur = 1;

            % update template definition
            app.refvol = regtmpl(app.img_cur, app.fixdef);

            % synchronize img_zproj.cur, img_zproj.ref
            % !!! must run after recover refvol
            SourceZProjection(app, [], app.mode_zproj);

            %% update system appearance
            % refresh the slice viewer display and mode selection
            SetSlider_Slice(app);

            SetSlider_Time(app, [1, app.img_cur.MetaData.frames]);

            % update the scalebar
            app.sbar.update(app.img_cur.MetaData);

            % refresh viewer
            RefreshImagingRange2D(app);
            RefreshViewer(app, "XY");
            UpdateYScrollLim(app);

            %% update sub apps
            switch nd.optr
                case constdef.OP_SEGMENT
                    % invoke segmentor handle (restart)
                    CloseSubApp(app, "CS");
                    app.CS_handle = CellSegmentor(app);
                    app.CS_handle.Recover(nd.cbot);

                otherwise
                    % skip
            end

            % unlock the display locker
            app.viewer_locker = false;
        end

        function opt = GetInitProfiles(app)
            opt = constdef.PROFILE_DEFAULT;

            % read from userpath for profiles
            prf_file = GetAppProfileFullPath(app);

            if isfile(prf_file)
                try
                    popt = readstruct(prf_file, "FileType","xml");
                    
                    % replace the same field in opt by popt
                    fns = reshape(string(fieldnames(opt)), 1, []);
                    pfns = string(fieldnames(popt));
                    for ns = fns
                        % TODO: Validate value
                        if ismember(ns, pfns), opt.(ns) = popt.(ns); end
                    end
                catch
                    throw(MException("Register:permissionDenied", ...
                        "Permission denied, please contact the administrator."));
                end
            else
                % no previous profile, use default profile
                % modify the external library
                pe = pyenv; 
                opt.PythonPath = pe.Executable;
            end
        end
        
        function InitData(app)
            % init locker first
            app.viewer_locker = true;
            app.loaded_flag = false;

            % init movie info
            app.img_cur = regmov.empty();
            app.img_zproj = struct("cur",[], "ref", []);
            app.mask_reg = [];

            % init registration options
            app.fixdef = struct("Sampling", ["mean", "1"], ...
                                "Channel",  "r"); 
            app.refvol = [];

            % init register kernel
            app.kernel = RegisterController(app, app.opt_reg, ...
                [app.opt_app.NumProtectedCPU, app.opt_app.NumProtectedGPU], ...
                app.distrib_flag);

            % init history manager
            % TODO: use given preference optimization
            app.ohmgr = regohm(app.Reg3DV400daVinciUIFigure, ...
                               app.Tree_Operations, ...
                               app.TextArea_OperationSnapshot, ...
                               app.ContextMenu_Operation, ...
                               app.opt_app.CachePolicy, ...
                               app.distrib_flag);

            % init cropped option, variable across data
            app.opt_crop = struct("xy",[],"z",[],"f",[]);

            % init app private variables
            app.fidx_cur = [];
            app.sidx_cur = [];
            app.cidx_cur = [];
            app.simg_cur = [];
            app.vimg_cur = [];
            app.c_order = [];
            app.hdle_image = [];
            app.hdle_volume = [];
            app.hdle_ROI = [];
            app.hdle_sArea = {};
            app.text_time = struct("handle",[],"text",[],"ismoving",[]);
            app.text_meta = struct("handle",[],"text",[],"ismoving",[]);
            app.mode_zproj = "none";
            app.mode_merge = "";
            app.pcolor = struct("normal",zeros(3), "fused",zeros(3));
            app.mode_dview = "2D";
            app.channel_locker = false;
            app.frames_reg = [];
            app.flag_playing = false;
            app.fps = struct("fold",1,"base",[]);
            app.graphics_opts = struct("r",[0,65535],"b",0,"c",1,"ga",1);
            app.path_save = "";
            app.pbx_old = 0;
            app.masked_flag = false;
            app.himgr_flag = false;
        end

        function status = DeployEnvConfig(app)
            % check java path
            % check C/C++ compiler
            javachk('jvm');
            javachk('swing');

            mex -setup C
            if GetAvailableMemory() <= 2.7e10
                % low memory, must use distributed mode
                app.distrib_flag = true;
            else
                app.distrib_flag = false;
            end
            pause(0.5);
            clc;

            if (feature('numCores') > 16) && (GetAvailableMemory() > 1.375e11) ...
                    && isunix()
                % more than 16 cores and 128GB available memory
                % server must use 'distributed' mode
                app.flag_server = true;
                app.distrib_flag = true;
                disp("Server mode enabled: too many CPU cores and memory installed on Unix.");
            else
                app.flag_server = false;
            end

            pe = pyenv;
            if app.flag_server == true
                % try to configurate the python environment
                try
                    pyenv(Version="/usr/share/miniconda3/envs/registration_matlab_env/bin/python3", ...
                        ExecutionMode="OutOfProcess");
                catch
                    warning("Register:pyenvConfigFailed", ...
                        "Can't configurate python environment, try to restart MATLAB.");
                    status = -1;
                    return;
                end
            else
                if pe.Version ~= ""
                    try
                        pyenv(ExecutionMode="OutOfProcess");
                    catch
                        warning("Register:pyenvConfigFailed", ...
                            "Can't configurate python environment, try to restart MATLAB.");
                        status = -1;
                        return;
                    end
                end
            end

            % close some warning
            warning('off', 'MATLAB:timer:miliSecPrecNotAllowed');

            % store the recycle configuration
            app.recycle_old_status = recycle();
            recycle("off");     % save disk space

            status = 0;
        end

        function InitTimer(app)
            % init player timer
            app.player_timer = timer("Period",app.PLAYER_TIMER_PERIOD,...
                "ExecutionMode","fixedSpacing","TasksToExecute",inf,...
                "Name","Timer_PlayControl","TimerFcn",@app.Play, ...
                "StopFcn",@app.Stop, "BusyMode","queue");

            % init progress bar timer
            app.progress_timer = timer("Period",app.PROGRESSBAR_TIMER_PERIOD,...
                "ExecutionMode","fixedSpacing","TasksToExecute",inf,...
                "Name", "Timer_ProgressControl","TimerFcn",@app.IndeteminateProgressBar, ...
                "BusyMode","queue");

            % init regdaemon timer
            app.regdaemon_timer = timer("Period",app.DAEMON_TIMER_PERIOD, ...
                "ExecutionMode","fixedSpacing", "TasksToExecute",inf, ...
                "Name","Timer_RegDaemon", "TimerFcn",@app.RegDaemonTask, ...
                "StartDelay",3, "BusyMode","queue");
        end

        function UpdateRegOptionsAfterLoading(app)
            if app.img_cur.MetaData.channels == 1
                app.opt_reg = regopt("OCREG", "global");
                app.opt_reg = set(app.opt_reg, "FC", app.c_order);  % fixed as view
                app.opt_reg = set(app.opt_reg, "SC", app.c_order);  % same as functional channel
                app.opt_locked = table('Size', [1,2], ...
                    'VariableTypes', repmat({'logical'},1,2), ...
                    'VariableNames', {'Global','MGlobal'});
            else
                app.opt_reg = regopt("TCREG", "global");
                app.opt_reg = set(app.opt_reg, "SC", app.c_order(1));
                app.opt_reg = set(app.opt_reg, "FC", app.c_order(2));
                app.opt_locked = table('Size', [1,4], ...
                    'VariableTypes', repmat({'logical'},1,4), ...
                    'VariableNames', {'Global','Local','MGlobal','LTGlobal'});

            end
            app.opt_reg = set(app.opt_reg, ...
                "glVPL", floor(log(app.img_cur.MetaData.slices)/log(4))+1);

            app.refvol = regtmpl(app.img_cur, app.fixdef);
        end

        function InitPanels(app)
            InitLayout(app);

            InitMenu(app);

            InitSlider_Slice(app);

            InitSlider_Time(app);

            InitContextMenu(app);

            InitControlPanel(app);
        end

        function UpdatePanelsAfterLoading(app)
            % dynamic context menu
            RemoveMergeChannelMenu(app);
            RemoveColorChannelMenu(app);
            AppendColorChannelMenu(app);
            RemoveCellManagerMenu(app);

            % dynamic viewer
            RemoveViewer(app);
            AppendViewer(app);

            % unlock the options
            app.Menu_SaveImageAs.Enable = "on";
            app.Menu_SaveTimeAs.Enable = "on";
            app.Menu_SaveMovieAs.Enable = "on";
            app.Menu_Image.Enable = "on";
            if isscalar(app.c_order)
                app.Menu_ChannelAlignment.Enable = "off";
            else
                app.Menu_ChannelAlignment.Enable = "on";
            end
            app.Menu_Animation.Enable = "on";
            app.Menu_Flip.Enable = "on";
            app.Menu_Rotate.Enable = "on";
            app.Menu_ROI.Enable = "on";
            app.Menu_Channel.Enable = "on";
            app.Menu_MaxZProjection.Checked = "off";
            app.Menu_MinZProjection.Checked = "off";
            app.Menu_MeanZProjection.Checked = "off";
            app.Menu_MedianZProjection.Checked = "off";
            app.Menu_TimeStamp.Checked = "off";
            app.Menu_MetaMarker.Checked = "off";
            app.Menu_Register.Enable = "on";
            app.Menu_PlayMode.Enable = "on";
            app.Menu_1F.Checked = "on";
            app.Menu_5F.Checked = "off";
            app.Menu_15F.Checked = "off";
            app.Menu_RegOptions.Enable = "on";
            app.Menu_RegRange.Enable = "on";
            app.Menu_CellSegmentor.Enable = "on";
            app.Menu_SignalExtractor.Enable = "off";
            app.DropDown_Source.Enable = "on";
            app.DropDown_Source.Items = [app.region.chmap.NORMAL];
            app.Button_Run.Enable = "on";
            app.SetProgressBar(0);

            % clear log if auto flag is true
            if app.auto_clear_log_flag
                ClearTextArea_Log(app);
                app.sysmsg = [];
            end

            GenSysMsg(app, ["IMAGE_LOADING_COMPLETE", " "+app.filename]);
        end

        function SetSystemLanguage(app)
            % set language by opt_app defination
            switch app.opt_app.Language
                case "CN"
                    UpdateRegion(app, "zh_CN");
                case "US"
                    UpdateRegion(app, "en_US");
                otherwise
                    app.region = GetRegionInfo();
                    UpdateRegion(app, app.region.lang);
            end

            % apply menu language
            app.Menu_File.Text = app.region.chmap.FILE;
            app.Menu_Image.Text = app.region.chmap.IMAGE;
            app.Menu_Options.Text = app.region.chmap.OPTION;
            app.Menu_Advanced.Text = app.region.chmap.ADVANCED;
            app.Menu_Help.Text = app.region.chmap.HELP;
            app.Menu_LoadImage.Text = app.region.chmap.LOAD_IMAGE;
            app.Menu_LoadTime.Text = app.region.chmap.LOAD_TIME;
            app.Menu_SaveImageAs.Text = app.region.chmap.EXPORT_IMAGES;
            app.Menu_SaveTimeAs.Text = app.region.chmap.EXPORT_TIME;
            app.Menu_SaveMovieAs.Text = app.region.chmap.EXPORT_MOVIE;
            app.Menu_Quit.Text = app.region.chmap.EXIT;
            app.Menu_LightContrast.Text = app.region.chmap.LIGHT_CONTRAST;
            app.Menu_ChannelAlignment.Text = app.region.chmap.CHANNEL_ALIGNMENT;
            app.Menu_ZProjection.Text = app.region.chmap.Z_PROJECTION;
            app.Menu_Comments.Text = app.region.chmap.APPEND_COMMENTS;
            app.Menu_Crop3D.Text = app.region.chmap.CROP_VOLUME;
            app.Menu_CropT.Text = app.region.chmap.CROP_FRAME;
            app.Menu_Flip.Text = app.region.chmap.FLIP;
            app.Menu_Rotate.Text = app.region.chmap.ROTATE;
            app.Menu_Animation.Text = app.region.chmap.ANIMATION;
            app.Menu_PlayMode.Text = app.region.chmap.PLAY_MODE;
            app.Menu_Register.Text = app.region.chmap.REGISTRATOR;
            app.Button_HistoryManager.Text ...
                = app.region.chmap.HISTORY_MANAGER.replace("",newline);
            app.Menu_RunModal.Text = app.region.chmap.RUN_MODAL;
            app.Menu_RunModalDistributed.Text = app.region.chmap.DISTRIBUTED;
            app.Menu_RunModalExclusive.Text = app.region.chmap.EXCLUSIVE;
            app.Menu_SaveParameters.Text = app.region.chmap.EXPORT_PARAMETERS;
            app.Menu_LoadParameters.Text = app.region.chmap.LOAD_PARAMETERS;
            app.Menu_ResourceManager.Text = app.region.chmap.RESOURCE_MANAGER;
            app.Menu_CellSegmentor.Text = app.region.chmap.CELL_SEGMENTOR;
            app.Menu_SignalExtractor.Text = app.region.chmap.SIGNAL_EXTRACTOR;
            app.Menu_About.Text = app.region.chmap.ABOUT;
            app.Menu_Documents.Text = app.region.chmap.DOCUMENT;
            app.Menu_MaxZProjection.Text = app.region.chmap.MAX_Z_PROJECTION;
            app.Menu_MinZProjection.Text = app.region.chmap.MIN_Z_PROJECTION;
            app.Menu_MedianZProjection.Text = app.region.chmap.MEDIAN_Z_PROJECTION;
            app.Menu_MeanZProjection.Text = app.region.chmap.MEAN_Z_PROJECTION;
            app.Menu_TimeStamp.Text = app.region.chmap.TIME_STAMP;
            app.Menu_ScaleBar.Text = app.region.chmap.SCALE_RATIO;
            app.Menu_MetaMarker.Text = app.region.chmap.METADATA;
            app.Menu_CropXY.Text = app.region.chmap.CROP_XY;
            app.Menu_CropZ.Text = app.region.chmap.CROP_Z;
            app.Menu_FlipHorizontal.Text = app.region.chmap.HORIZONTAL;
            app.Menu_FlipVertical.Text = app.region.chmap.VERTICAL;
            app.Menu_RotateClockwise90.Text = app.region.chmap.CLOCKWISE_90;
            app.Menu_RotateCounterclockwise90.Text = app.region.chmap.COUNTERCLOCKWISE_90;
            app.Menu_Direct.Text = app.region.chmap.DIRECT;
            app.Menu_Loop.Text = app.region.chmap.RECYCLE;
            app.Menu_RegOptions.Text = app.region.chmap.REGISTRATION_SETTING;
            app.Menu_RegRange.Text = app.region.chmap.REGISTRATION_RANGE;
            app.Menu_RegEvaluate.Text = app.region.chmap.REGISTRATION_EVALUATION;

            % apply main panel language
            switch app.mode_dview
                case "2D"
                    app.Panel_Viewer.Title = app.region.chmap.SLICE_VIEW;
                case "3D"
                    app.Panel_Viewer.Title = app.region.chmap.VOLUME_VIEW;
                otherwise
            end
            app.Panel_Control.Title = app.region.chmap.CONTROL_PANEL;
            app.Panel_OperationsViewer.Title = app.region.chmap.OPERATION_VIEW;
            app.Panel_StorageViewer.Title = app.region.chmap.STORAGE_VIEW;
            app.Panel_OperationSnapshot.Title = app.region.chmap.OPERATION_SNAPSHOT;
            app.Label_2.Text = app.region.chmap.SLICE;
            app.Spinner_Slice.Tooltip = app.region.chmap.TIP_SPN_SLICE;
            app.Spinner_Time.Tooltip = app.region.chmap.TIP_SPN_TIME;
            app.Label.Text = app.region.chmap.TIME_FRAME;
            app.GridLayout_OuterPB.Tooltip = app.region.chmap.TIP_SLR_PROGRESS;
            app.Slider_Time.Tooltip = app.region.chmap.TIP_SLR_TIME;
            app.Label_19.Text = app.region.chmap.SOURCE;
            app.Label_20.Text = app.region.chmap.LOG;
            app.Switch_Log.Items = [app.region.chmap.OFF, app.region.chmap.ON];
            app.Button_Run.Text = app.region.chmap.RUN;

            % apply static context menu language
            % ContextMenu_Player
            app.Menu_ROI.Text = app.region.chmap.ROI_SETTING;
            app.Menu_Channel.Text = app.region.chmap.COLOR_CHANNEL;
            app.Menu_DisplayMode.Text = app.region.chmap.DISPLAY_MODE;
            app.Menu_RectangleROI.Text = app.region.chmap.RECTANGLE;
            app.Menu_CircleROI.Text = app.region.chmap.CIRCLE;
            app.Menu_PolygonROI.Text = app.region.chmap.POLYGON;
            app.Menu_FreehandROI.Text = app.region.chmap.REGION_FREE;
            % ContextMenu Controller
            app.Menu_ExportLog.Text = app.region.chmap.EXPORT;
            app.Menu_ClearLog.Text = app.region.chmap.CLEAR;
            app.Menu_AutoClearLog.Text = app.region.chmap.AUTO_CLEAR;
            % ContextMenu Operation
            app.Menu_DeleteNode.Text = app.region.chmap.DELETE_NODE;
            app.Menu_ActivateNode.Text = app.region.chmap.ACTIVATE_NODE;

            % apply dynamic context menu language
            if ~isempty(app.Menu_MergeSource) && isvalid(app.Menu_MergeSource)
                app.Menu_MergeSource.Text = app.region.chmap.MERGE_SOURCE;
                app.Menu_MergeRB.Text = app.region.chmap.RED_CYAN;
                app.Menu_MergeGR.Text = app.region.chmap.GREEN_MAGENTA;
            end
            if ~isempty(app.Menu_Red) && isvalid(app.Menu_Red)
                app.Menu_Red.Text = app.region.chmap.RED;
            end
            if ~isempty(app.Menu_Green) && isvalid(app.Menu_Green)
                app.Menu_Green.Text = app.region.chmap.GREEN;
            end
            if ~isempty(app.Menu_Red_Green) && isvalid(app.Menu_Red_Green)
                app.Menu_Red_Green.Text = app.region.chmap.RED_GREEN;
            end

            % translate the log message
            if ~isempty(app.TextArea_Log.Value{1}) && app.TextArea_Log.Value{1} ~= ""
                for k = 1:numel(app.TextArea_Log.Value)
                    if app.sysmsg(k, 3) ~= ""
                        app.TextArea_Log.Value{k} = [app.sysmsg(k, 3).char(), ...
                            ' ', app.region.chmap.(app.sysmsg(k, 1)).char(), ...
                            app.sysmsg(k, 2).char()];
                    else
                        app.TextArea_Log.Value{k} = ...
                            [app.region.chmap.(app.sysmsg(k, 1)).char(), ...
                            app.sysmsg(k, 2).char()];
                    end
                end
            end

            % apply sub app language
            RefreshAllSubAppLanguage(app);
        end

        function RefreshSystemStyle(app)
            style = app.opt_app.Style;

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr > 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    app.GridLayout_RegBody.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_Viewer.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_ImageViewer.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_ViewBody.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_MainWin.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_OuterPB.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_SidebarRegion.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_Control.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Tree_Operations.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_OperationsViewer.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_StorageViewer.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_StorageViewer.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Panel_OperationSnapshot.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                case "DARK"
                    app.GridLayout_RegBody.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_Viewer.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_ImageViewer.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_ViewBody.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_MainWin.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_OuterPB.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_SidebarRegion.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_Control.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Tree_Operations.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_OperationsViewer.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_StorageViewer.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_StorageViewer.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Panel_OperationSnapshot.BackgroundColor = constdef.GRAYSCALE_DARK;
                otherwise
            end

            RefreshAllSubAppStyle(app);
        end

        function UpdateAppProfiles(app)
            %% Update Appearance
            % update language
            SetSystemLanguage(app);

            % refresh toolbar
            if ~isempty(app.img_cur) && app.mode_dview == "2D"
                AppendToolBar2D(app);
            end

            % refresh style
            RefreshSystemStyle(app);

            % set progress bar color
            app.Label_ProgressBar.BackgroundColor = app.opt_app.ProgressBarColor;

            %% Update Operation History Undo/Redo/Batch Function

            %% Update Kernel Option
            app.kernel.NWorkersProtected = [app.opt_app.NumProtectedCPU, ...
                                            app.opt_app.NumProtectedGPU];

            %% Update Logger
            Register.SetLoggerState(app.opt_app.MessageLevel);
        end

        % This function loop by regdaemon every 5s
        function RegDaemonTask(app, ~, ~)
            persistent ups
            if isempty(ups), ups = 0; end

            %% 1. Validate if "Appearance" need to change (if follow system)
            % usual take < 0.1s
            switch app.opt_app.Style
                case "FOLLOW"
                    % refresh at only two time points
                    tcode = str2double(string(datetime("now","Format","HH:mm:ss")).split(":"));
                    if (tcode(1)==6 && tcode(2)==0 && tcode(3)>0 && tcode(3)<=app.DAEMON_TIMER_PERIOD) ...
                            || (tcode(1)==18 && tcode(2)==0 && tcode(3)>0 && tcode(3)<=app.DAEMON_TIMER_PERIOD)
                        RefreshSystemStyle(app);
                    end
                otherwise
            end

            %% 2. Validate if "Update" need to run
            % when goes false, usual take ~ 3s
            switch app.opt_app.AutoUpdate
                case "EVERYDAY"
                    % refresh at only one time point
                    tcode = str2double(string(datetime("now","Format","HH:mm:ss")).split(":"));
                    if (tcode(1)==0 && tcode(2)==0 && tcode(3)>0 && tcode(3)<=app.DAEMON_TIMER_PERIOD)
                        ValidateUpdate(app);
                    end
                case "RT"
                    % break, avoid UI operation overflow
                    if ups ~= -1, ups = ValidateUpdate(app); end

                otherwise
                    ups = 0;        % reset update profile status
            end

            %% 3. Validate if "Temporary Files" need to be cleaned
            switch app.opt_app.BufferCleanTrigger
                case "RT"
                    mpimg.clean_temporary_folder(app.opt_app.Capacity);
                    mpimgs.clean_temporary_folder(app.opt_app.Capacity);
                otherwise
            end

        end

        function InitLayout(app)
            if app.himgr_flag == false
                app.Reg3DV400daVinciUIFigure.Position(3) = app.LAYOUT_WIDTH_INIT;
                app.Reg3DV400daVinciUIFigure.Position(4) = app.LAYOUT_HEIGHT_INIT;
                app.GridLayout_RegView.ColumnWidth = {'1x', 0};
            else
                app.Reg3DV400daVinciUIFigure.Position(3) = ...
                    app.LAYOUT_WIDTH_INIT + app.RIGHT_SIDEBAR_WIDTH;
                app.Reg3DV400daVinciUIFigure.Position(4) = app.LAYOUT_HEIGHT_INIT;
                app.GridLayout_RegView.ColumnWidth = {'1x', app.RIGHT_SIDEBAR_WIDTH};
            end
        end

        function InitMenu(app)
            app.Menu_SaveImageAs.Enable = "off";
            app.Menu_SaveTimeAs.Enable = "off";
            app.Menu_SaveMovieAs.Enable = "off";
            app.Menu_Image.Enable = "off";
            app.Menu_RegOptions.Enable = "off";
            app.Menu_RegEvaluate.Enable = "off";
            app.Menu_RegRange.Enable = "off";
            app.Menu_Register.Enable = "off";
            app.Menu_Animation.Enable = "off";
            app.Menu_MaxZProjection.Checked = "off";
            app.Menu_MinZProjection.Checked = "off";
            app.Menu_MeanZProjection.Checked = "off";
            app.Menu_MedianZProjection.Checked = "off";
            app.Menu_CellSegmentor.Enable = "off";
            app.Menu_SignalExtractor.Enable = "off";
            app.Menu_PlayMode.Enable = "off";
            app.Menu_Direct.Checked = "on";
            app.Menu_Loop.Checked = "off";
            app.Menu_1F.Checked = "on";
            app.Menu_5F.Checked = "off";
            app.Menu_15F.Checked = "off";

            if app.distrib_flag == true
                % unix or server
                app.Menu_RunModalDistributed.Checked = "on";
                app.Menu_RunModalExclusive.Checked = "off";
            else
                % high performance pc(Windows), exclusive is possible
                app.Menu_RunModalDistributed.Checked = "off";
                app.Menu_RunModalExclusive.Checked = "on";
            end
        end

        function InitContextMenu(app)
            app.Menu_ROI.Enable = "off";
            app.Menu_Channel.Enable = "off";
            app.Menu_DisplayMode.Enable = "on";

            % dynamic context menu
            RemoveMergeChannelMenu(app);
            RemoveColorChannelMenu(app);

            app.Menu_View2D.Checked = "off";
            app.Menu_View3D.Checked = "off";
            switch app.mode_dview
                case "2D"
                    app.Menu_View2D.Checked = "on";
                case "3D"
                    app.Menu_View3D.Checked = "on";
                otherwise
            end
        end

        function InitSlider_Time(app)
            app.Slider_Time.Limits = [0,1];
            app.Slider_Time.Value = 0;
            app.Slider_Time.Enable = "off";
            app.Spinner_Time.Enable = "off";
        end

        function InitControlPanel(app)
            app.DropDown_Source.Enable = "off";
            app.Button_Run.Enable = "off";
            app.mode_loop = "one-step";
            app.TextArea_Log.Placeholder = app.region.chmap.WELCOME;
        end

        function AppendViewer2D(app)
            app.UIAxes_Slice = uiaxes(app.GridLayout_MainWin);
            xlabel(app.UIAxes_Slice, 'X')
            ylabel(app.UIAxes_Slice, 'Y')
            zlabel(app.UIAxes_Slice, 'Z')

            app.UIAxes_Slice.Visible = 'off';
        end

        function AppendViewer3D(app)
            if isMATLABReleaseOlderThan("R2023b")
                app.UIViewer_Vol = viewer3d(app.GridLayout_MainWin, "BackgroundColor","black", ...
                    "Box","on","BackgroundGradient","off","Lighting","on", ...
                    "Interactions",["zoom","rotate","axes","pan"]);
            elseif isMATLABReleaseOlderThan("R2024a")
                app.UIViewer_Vol = viewer3d(app.GridLayout_MainWin, "BackgroundColor","black", ...
                    "Box","on","BackgroundGradient","off","Denoising","on","Lighting","on",...
                    "Interactions",["zoom","rotate","axes","pan"]);
            else
                app.UIViewer_Vol = viewer3d(app.GridLayout_MainWin, "BackgroundColor","black", ...
                    "Box","on","BackgroundGradient","off","Denoising","on","Lighting","on",...
                    "Interactions",["zoom","rotate","axes","pan","annotate"]);
            end
        end

        function InitSlider_Slice(app)
            app.Slider_Slice.Limits = [0,1];
            app.Slider_Slice.Value = 0;
            app.Slider_Slice.Enable = "off";
            app.Spinner_Slice.Enable = "off";
        end
        
        % This function for 2d image display
        function im_H = InitUIAxes_Slice(app)
            % add customized toolbar on axes
            AppendToolBar2D(app);

            % Note that image can change the figure properties, so that
            % figure setting must after the image setting
            % initialize image handle
            im_H = image(app.UIAxes_Slice,...
                [0.5 app.img_cur.MetaData.width-0.5],...
                [0.5 app.img_cur.MetaData.height-0.5],...
                [],"Interpolation","bilinear"); % bilinear offer better visual effort

            app.hdle_image = im_H;

            % set coordinate
            RefreshImagingRange2D(app);

            drawnow nocallbacks
            % show the viewer
            app.UIAxes_Slice.Visible = "on";

            UpdateYScrollLim(app);

            app.Label_ImageInfo.Visible = "on";
        end

        % This function for 3d volume display
        function vol_H = InitUIViewer_Vol(app)
            rs = min(app.img_cur.MetaData.xRes, app.img_cur.MetaData.yRes);
            vol_H = volshow([], "Parent", app.UIViewer_Vol, ...
                "RenderingStyle", "MaximumIntensityProjection", ...
                "Transformation", affinetform3d(diag([rs,rs,rs,1])));

            app.hdle_volume = vol_H;

            UpdateYScrollLim(app);

            app.UIViewer_Vol.ScaleBarUnits = "um";
            app.UIViewer_Vol.Visible = "on";
            app.Label_ImageInfo.Visible = "on";
        end

        function iv_H = InitViewer(app)
            switch app.mode_dview
                case "2D"
                    iv_H = InitUIAxes_Slice(app);
                case "3D"
                    iv_H = InitUIViewer_Vol(app);
                otherwise
            end
        end

        function AppendToolBar2D(app)
            pathToMLAPP = fileparts(mfilename('fullpath'));

            % append initialized toolbar with:
            app.hdle_axtb = []; % remove previous toolbar
            app.hdle_axtb = axtoolbar(app.UIAxes_Slice, {'export','zoomin','zoomout','pan'});

            if app.opt_app.SimpleStatistics == "ON"
                % append simple function
                btn_stat = axtoolbarbtn(app.hdle_axtb, "push");
                btn_stat.Icon = fullfile(pathToMLAPP, 'sources', 'stat_icon.png');
                btn_stat.Tooltip = app.region.chmap.SIMPLE_STAT;
                btn_stat.ButtonPushedFcn = @app.SimpleStat_CallBack;
            end

            if app.opt_app.AutoTemplate == "ON"
                % append auto template function only if red channel exist
                if any(app.img_cur.MetaData.cOrder=="r")
                    btn_autotmpl = axtoolbarbtn(app.hdle_axtb, "push");
                    btn_autotmpl.Icon = fullfile(pathToMLAPP, 'sources', 'autotmpl_icon.png');
                    btn_autotmpl.Tooltip = app.region.chmap.AUTO_TEMPLATE;
                    btn_autotmpl.ButtonPushedFcn = @app.FindTemplateAuto_CallBack;
                end
            end

            % append overload "restore view" function
            btn_rsc = axtoolbarbtn(app.hdle_axtb, "push");
            btn_rsc.Icon = fullfile(pathToMLAPP, 'sources', 'restore_view_icon.png');
            btn_rsc.Tooltip = app.region.chmap.RESTORE_VIEW;
            btn_rsc.ButtonPushedFcn = @app.RestoreView2D_CallBack;
        end

        function RefreshImagingRange2D(app)
            % initialize figure
            app.UIAxes_Slice.XLim = [0.5, app.img_cur.MetaData.width-0.5];
            app.UIAxes_Slice.YLim = [0.5, app.img_cur.MetaData.height-0.5];
            app.UIAxes_Slice.XTick = [];
            app.UIAxes_Slice.YTick = [];

            set(app.hdle_image, 'XData', [0.5, app.img_cur.MetaData.width-0.5]);
            set(app.hdle_image, 'YData', [0.5, app.img_cur.MetaData.height-0.5]);

            pbaspect(app.UIAxes_Slice, [app.img_cur.MetaData.width, ...
                                        app.img_cur.MetaData.height,...
                                        1]);
        end
        
        %======================= PLAYER CONTROL ==========================
        function UpdateYScrollLim(app)
            switch app.mode_dview
                case "2D"
                    % calculate scroll y limitation
                    tp = app.UIAxes_Slice.tightPosition();
                    % xr = diff(app.UIAxes_Slice.XLim)/tp(3)*app.Panel_Control.Position(3);
                    yr = diff(app.UIAxes_Slice.YLim)/tp(4)*app.Panel_Viewer.Position(4);
                    % xvalid = 0.5*diff(app.UIAxes_Slice.XLim) + 0.5*[-xr, xr];
                    tiset = app.UIAxes_Slice.TightInset;
                    app.scroll_ylim = 0.5*diff(app.UIAxes_Slice.YLim) + ...
                        0.5*[-yr, yr] + 0.5*[tiset(2)+20, tiset(4)];
                case "3D"
                    % slice locked, Y scroll limit can be omit
                otherwise
            end
            
        end

        function ScalingProgressBar(app)
            % update progress bar
            figwc = app.Reg3DV400daVinciUIFigure.Position(3);
            lnew = (figwc - (60*2+10*4+7*2))*app.pbx_old;
            app.GridLayout_ProgressBar.ColumnWidth{2} = lnew;
            drawnow
        end

        function UpdatePlayerTimer(app, nframes, fps)
            if ~isempty(app.player_timer) && isvalid(app.player_timer)
                app.player_timer.Period = round(1/fps, 3);
                app.player_timer.TasksToExecute = nframes;
            end
        end
        
        function NextFramePtr(app)
            if ~isempty(app.fidx_cur) && app.channel_locker == false
                if app.fidx_cur < app.Slider_Time.Limits(end)
                    app.fidx_cur = app.fidx_cur + 1;
                else
                    switch app.mode_loop
                        case "one-step"
                            % do nothing
                        case "loop"
                            app.fidx_cur = app.Slider_Time.Limits(1);
                    end
                end
                app.Slider_Time.Value = app.fidx_cur;
                app.Spinner_Time.Value = app.fidx_cur;
                app.frames_reg(2) = string(app.fidx_cur);
            end
        end

        function PrevFramePtr(app)
            if ~isempty(app.fidx_cur) && app.channel_locker == false
                if app.fidx_cur > app.Slider_Time.Limits(1)
                    app.fidx_cur = app.fidx_cur - 1;
                else
                    switch app.mode_loop
                        case "one-step"
                            % do nothing
                        case "loop"
                            app.fidx_cur = app.Slider_Time.Limits(end);
                    end
                end
                app.Slider_Time.Value = app.fidx_cur;
                app.Spinner_Time.Value = app.fidx_cur;
                app.frames_reg(2) = string(app.fidx_cur);
            end
        end

        function NextSlicePtr(app)
            if ~isempty(app.sidx_cur) && app.mode_zproj == "none" ...
                    && app.channel_locker == false
                if app.sidx_cur < app.Slider_Slice.Limits(end)
                    app.sidx_cur = app.sidx_cur + 1;
                else
                    switch app.mode_loop
                        case "one-step"
                            % do nothing
                        case "loop"
                            app.sidx_cur = app.Slider_Slice.Limits(1);
                    end
                end
                app.Slider_Slice.Value = app.sidx_cur;
                app.Spinner_Slice.Value = app.sidx_cur;
            end
        end

        function PrevSlicePtr(app)
            if ~isempty(app.sidx_cur) && app.mode_zproj == "none" ...
                    && app.channel_locker == false
                if app.sidx_cur > app.Slider_Slice.Limits(1)
                    app.sidx_cur = app.sidx_cur - 1;
                else
                    switch app.mode_loop
                        case "one-step"
                            % do nothing
                        case "loop"
                            app.sidx_cur = app.Slider_Slice.Limits(end);
                    end
                end
                app.Slider_Slice.Value = app.sidx_cur;
                app.Spinner_Slice.Value = app.sidx_cur;
            end
        end

        function Play(app, src, event)
            src; %#ok<VUNUS>
            event; %#ok<VUNUS>
            if ~app.channel_locker
                NextFramePtr(app);
                RefreshViewer(app, "T");
            end
        end

        function Stop(app, src, event)
            src; %#ok<VUNUS>
            event; %#ok<VUNUS>
            UnlockPlaySetting(app);
        end

        function RefreshViewer(app, dview)
            arguments
                app
                dview   (1,1)   string {mustBeMember(dview, ["XY", "Z","T"])} = "T"
            end
            switch app.mode_dview
                case "2D"
                    RefreshViewer2D(app, dview);
                case "3D"
                    RefreshViewer3D(app);
                otherwise
            end
        end

        function RefreshViewer2D(app, dview)
            if ~app.channel_locker
                % update and draw current slice
                UpdateCurrentSlice(app);

                DrawCurrentSlice(app);

                % update and draw mROI objects, text objects
                DrawUtilsComponents(app, dview);

                % follow the mouse
                Reg3DV400daVinciUIFigureWindowButtonMotion(app);
            end
        end

        function RefreshViewer3D(app)
            % only support "T" mode
            UpdateCurrentVolume(app);

            DrawCurrentVolume(app);

            UpdateViewerTitle(app);

            DrawUtilsComponents(app, "T");      % "T" can be omitted
        end

        function LockViewerController(app)
            app.Slider_Slice.Enable = "off";
            app.Spinner_Slice.Enable = "off";
            app.Slider_Time.Enable = "off";
            app.Spinner_Time.Enable = "off";
            app.Menu_Animation.Enable = "off";
            app.Menu_Channel.Enable = "off";
        end

        function UnlockViewerController(app)
            if app.mode_zproj == "none"
                app.Slider_Slice.Enable = "on";
                app.Spinner_Slice.Enable = "on";
            end
            app.Slider_Time.Enable = "on";
            app.Spinner_Time.Enable = "on";
            app.Menu_Animation.Enable = "on";
            app.Menu_Channel.Enable = "on";
        end

        function LockComponents_3DView(app)
            app.Menu_SaveMovieAs.Enable = "off";
            app.Menu_ZProjection.Enable = "off";
            app.Menu_ChannelAlignment.Enable = "off";
            app.Menu_Comments.Enable = "off";
            app.Menu_Crop3D.Enable = "off";
            app.Menu_Flip.Enable = "off";
            app.Menu_Rotate.Enable = "off";
            app.Menu_CellSegmentor.Enable = "off";
            app.Menu_SignalExtractor.Enable = "off";

            app.Slider_Slice.Enable = "off";
            app.Spinner_Slice.Enable = "off";

            app.Menu_ROI.Enable = "off";
        end

        function UnlockComponents_2DView(app)
            app.Menu_SaveMovieAs.Enable = "on";
            app.Menu_ZProjection.Enable = "on";
            if ~isscalar(app.c_order)
                app.Menu_ChannelAlignment.Enable = "on";
            end
            app.Menu_Comments.Enable = "on";
            app.Menu_Crop3D.Enable = "on";
            app.Menu_Flip.Enable = "on";
            app.Menu_Rotate.Enable = "on";
            app.Menu_CellSegmentor.Enable = "on";

            app.Menu_ROI.Enable = "on";
        end
        
        %===================== PLAYER SETTING ============================
        
        % player components: lock play mode menu
        function LockPlaySetting(app)
            app.Menu_Loop.Enable = "off";
            app.Menu_Direct.Enable = "off";
            app.Menu_1F.Enable = "off";
            app.Menu_5F.Enable = "off";
            app.Menu_15F.Enable = "off";
        end

        % player components: unlock play mode menu
        function UnlockPlaySetting(app)
            app.Menu_Loop.Enable = "on";
            app.Menu_Direct.Enable = "on";
            app.Menu_1F.Enable = "on";
            app.Menu_5F.Enable = "on";
            app.Menu_15F.Enable = "on";
        end

        % player components: time slider
        function SetSlider_Time(app, limits)
            LABEL_COUNTS = 20;
            app.Slider_Time.Enable = "on";
            app.Spinner_Time.Enable = "on";

            if ~isempty(app.img_cur) && app.Slider_Time.Enable == true
                if ~isempty(limits)
                    app.Slider_Time.Limits = limits;
                    app.Spinner_Time.Limits = limits;
                else
                    % keep the limits
                    limits = app.Slider_Time.Limits;
                end

                deltaT = round((limits(2)-limits(1))/LABEL_COUNTS);
                if ~isequal(app.Slider_Time.MajorTicks,limits(1):deltaT:limits(2))
                    app.Slider_Time.MajorTicks = limits(1):deltaT:limits(2);
                    app.Slider_Time.MajorTickLabels = string(app.Slider_Time.MajorTicks);
                end

                app.Slider_Time.Value = app.fidx_cur;
                app.Spinner_Time.Value = app.fidx_cur;
            end
        end

        % player components: slice slider
        function SetSlider_Slice(app, state)
            if exist("state","var") && ~isempty(state)
                switch state
                    case 'on'
                        app.Slider_Slice.Enable = "on";
                        app.Spinner_Slice.Enable = "on";
                    case 'off'
                        app.Slider_Slice.Limits = [0,1];
                        app.Slider_Slice.Value = 0;
                        app.Slider_Slice.Enable = "off";
                        app.Spinner_Slices.Limits = [0,1];
                        app.Spinner_Slices.Value = 0;
                        app.Spinner_Slice.Enable = "off";
                    case 'freeze'
                        app.Slider_Slice.Enable = "off";
                        app.Spinner_Slice.Enable = "off";
                end
            else
                % keep the state
            end

            if ~isempty(app.img_cur)
                app.Slider_Slice.Limits = [1, app.img_cur.MetaData.slices];
                app.Spinner_Slice.Limits = [1, app.img_cur.MetaData.slices];

                app.Slider_Slice.MajorTicks = 1:5:app.img_cur.MetaData.slices;
                app.Slider_Slice.MinorTicks = 1:app.img_cur.MetaData.slices;

                app.Slider_Slice.Value = app.sidx_cur;
                app.Spinner_Slice.Value = app.sidx_cur;
            end
        end

        function IndeteminateProgressBar(app, obj, event)
            % This function set the progress bar running indeteminatlly
            obj; %#ok<VUNUS>
            event; %#ok<VUNUS>
            persistent x;
            if isempty(x)
                x = 0;
            else
                x = mod(x+1, 60);
            end
            len = app.Reg3DV400daVinciUIFigure.Position(3) - (60*2+10*4+7*2);
            barl = round(min([len/5, x/50*len, (60-x)*len/50]));
            bars = round(max(x*len/50-len/5, 0));

            app.GridLayout_ProgressBar.ColumnWidth = {bars, barl};
            drawnow nocallbacks
        end

        % player components: viewer body
        function AppendViewer(app)
            % append main display window body
            switch app.mode_dview
                case "2D"
                    AppendViewer2D(app);
                    
                    % attach scale bar
                    app.sbar = scalebar(app.UIAxes_Slice);
                    app.sbar.update(app.img_cur.MetaData);
                case "3D"
                    AppendViewer3D(app);

                    app.UIViewer_Vol.ScaleBar = "on";
                otherwise
                    throw(MException("invalidViewMode", ...
                        "Error using of InitViewer: unsupported viewing dimension."));
            end

            % append progress bar (removed), instead of fixed location
        end

        function RemoveViewer(app)
            if ~isempty(app.UIAxes_Slice) && isvalid(app.UIAxes_Slice)
                delete(app.UIAxes_Slice);
            end
            if ~isempty(app.UIViewer_Vol) && isvalid(app.UIViewer_Vol)
                delete(app.UIViewer_Vol);
            end
        end

        % player components: viewer window
        function DrawCurrentSlice(app)
            if ~isempty(app.simg_cur)
                % primary version for fast rendering
                % fill the new image
                set(app.hdle_image,'CData',app.simg_cur);

                % update image immediately
                % limit as 20 fps
                drawnow limitrate nocallbacks
            end
        end

        function DrawCurrentVolume(app)
            if ~isempty(app.vimg_cur)
                % input data and review
                app.hdle_volume.Data = app.vimg_cur;

                % update image immediately
                drawnow nocallbacks
            end
        end

        % player components: viewer title
        function UpdateViewerTitle(app, cursorPos, grayVal)
            if ~exist("cursorPos","var") || isempty(cursorPos)
                cursorPos = 'None';
            else
                cursorPos = ['(',num2str(cursorPos(1)),',',...
                    num2str(cursorPos(2)),')'];
            end
            if ~exist("grayVal","var") || isempty(grayVal)
                grayVal = 'None';
            else
                grayVal = num2str(grayVal);
            end

            % auto generated by exist uROI
            if isempty(app.hdle_ROI) || ~isvalid(app.hdle_ROI)
                roi = "None";
            else
                R = ROI2Rectangle(app.hdle_ROI);
                roi = diff(ceil(R), 1, 1) + 1;
                roi = ['w = ',num2str(roi(1)),', h = ',num2str(roi(2))];
            end

            app.Label_ImageInfo.Text = "Cursor: " + cursorPos + ...
                ",   Value: " + grayVal + ...
                ",   Time: "  + sprintf("%.1f", app.img_cur.Time(app.fidx_cur)) + " s" + ...
                ",   ROI: "   + roi;
        end

        % player components: dynamic add merge channel
        function AppendMergeChannelMenu(app, state)
            arguments
                app 
                state   (1,1)   string  {mustBeMember(state, ["on", "off"])} = "off" 
            end
            % construct the parent menu
            app.Menu_MergeSource = uimenu(app.ContextMenu_Player);
            app.Menu_MergeSource.Enable = state;
            app.Menu_MergeSource.Text = app.region.chmap.MERGE_SOURCE;

            % construct the children menu
            %
            app.Menu_MergeRB = uimenu(app.Menu_MergeSource);
            app.Menu_MergeRB.MenuSelectedFcn = createCallbackFcn(app, @Menu_MergeRBSelected, true);
            app.Menu_MergeRB.Enable = "on";
            app.Menu_MergeRB.Text = app.region.chmap.RED_CYAN;
            app.Menu_MergeRB.Checked = "on";
            %
            app.Menu_MergeGR = uimenu(app.Menu_MergeSource);
            app.Menu_MergeGR.MenuSelectedFcn = createCallbackFcn(app, @Menu_MergeGRSelected, true);
            app.Menu_MergeGR.Enable = "on";
            app.Menu_MergeGR.Text = app.region.chmap.GREEN_MAGENTA;
            app.Menu_MergeGR.Checked = "off";
        end

        % player components: dynamic remove merge channel
        function RemoveMergeChannelMenu(app)
            if ~isempty(app.Menu_MergeSource) ...
                    && isvalid(app.Menu_MergeSource)
                delete(app.Menu_MergeSource);
            end
        end

        % player components: dynamic add color channel
        function AppendColorChannelMenu(app)
            % append red channel menu
            if ismember("r", app.c_order)
                app.Menu_Red = uimenu(app.Menu_Channel);
                app.Menu_Red.MenuSelectedFcn = createCallbackFcn(app, @Menu_RedSelected, true);
                app.Menu_Red.Enable = "on";
                app.Menu_Red.Text = app.region.chmap.RED;
                app.Menu_Red.Checked = "off";
            end
            
            % append green channel menu
            if ismember("g", app.c_order)
                app.Menu_Green = uimenu(app.Menu_Channel);
                app.Menu_Green.MenuSelectedFcn = createCallbackFcn(app, @Menu_GreenSelected, true);
                app.Menu_Green.Enable = "on";
                app.Menu_Green.Text = app.region.chmap.GREEN;
                app.Menu_Green.Checked = "off";
            end

            % append merged channel menu
            if all(ismember(["r","g"], app.c_order))
                app.Menu_Red_Green = uimenu(app.Menu_Channel);
                app.Menu_Red_Green.MenuSelectedFcn = createCallbackFcn(app, @Menu_Red_GreenSelected, true);
                switch app.mode_merge
                    case "NORMAL"
                        app.Menu_Red_Green.Enable = "on";
                    case "FUSED"
                        app.Menu_Red_Green.Enable = "off";
                    otherwise
                end
                app.Menu_Red_Green.Text = app.region.chmap.RED_GREEN;
                app.Menu_Red_Green.Checked = "off";
            end

            c_cur = app.c_order(app.cidx_cur);
            if isscalar(c_cur)
                switch c_cur
                    case "r"
                        app.Menu_Red.Checked = "on";
                    case "g"
                        app.Menu_Green.Checked = "on";
                    otherwise
                end
            elseif numel(c_cur) == 2
                app.Menu_Red_Green.Checked = "on";
            else
                error(app.region.chmap.UNSUPPORTED_CHANNEL_NUMBER);
            end
        end

        % player components: dynamic remove color channel
        function RemoveColorChannelMenu(app)
            if ~isempty(app.Menu_Red) && isvalid(app.Menu_Red)
                delete(app.Menu_Red);
            end
            if ~isempty(app.Menu_Green) && isvalid(app.Menu_Green)
                delete(app.Menu_Green);
            end
            if ~isempty(app.Menu_Red_Green) && isvalid(app.Menu_Red_Green)
                delete(app.Menu_Red_Green);
            end
        end

        % player components: dynamic add cell management menu
        function AppendCellManagerMenu(app)
            % construct the parent menu
            app.Menu_CellManager = uimenu(app.ContextMenu_Player);
            app.Menu_CellManager.Enable = "on";
            app.Menu_CellManager.Text = app.region.chmap.CELL_MANAGEMENT;

            % construct the children menu
            % [Add Cell]
            app.Menu_AddCell = uimenu(app.Menu_CellManager);
            app.Menu_AddCell.MenuSelectedFcn = createCallbackFcn(app, @Menu_AddCellSelected, true);
            app.Menu_AddCell.Enable = "on";
            app.Menu_AddCell.Text = app.region.chmap.ADD_CELL;
            % [Refresh Label]
            app.Menu_RefreshLabel = uimenu(app.Menu_CellManager);
            app.Menu_RefreshLabel.MenuSelectedFcn = createCallbackFcn(app, @Menu_RefreshLabelSelected, true);
            app.Menu_RefreshLabel.Enable = "on";
            app.Menu_RefreshLabel.Text = app.region.chmap.REFRESH_LABEL;
            % [Anomaly Detection]
            app.Menu_AnomalyDetection = uimenu(app.Menu_CellManager);
            app.Menu_AnomalyDetection.MenuSelectedFcn = createCallbackFcn(app, @Menu_AnomalyDetectionSelected, true);
            app.Menu_AnomalyDetection.Enable = "on";
            app.Menu_AnomalyDetection.Text = app.region.chmap.ANOMALY_DETECTION;
        end

        % player components: dynamic remove cell management menu
        function RemoveCellManagerMenu(app)
            if ~isempty(app.Menu_CellManager) && isvalid(app.Menu_CellManager)
                delete(app.Menu_CellManager);
            end
        end

        %==================== CONTROL PANEL SETTING ======================

        function ClearTextArea_Log(app)
            % clear log buffer
            if ~isempty(app.TextArea_Log.Value)
                app.TextArea_Log.Value = "";
            end
        end

        function ExportTextArea_Log(app, path)
            t = datetime('now','TimeZone','local','Format','yyMMdd_HHmmss');
            fname = fullfile(path, ['log_',char(t),'.txt']);
            writecell(app.TextArea_Log.Value, fname);
        end

        %====================== INDICATE SET/GET =========================
        function timeMsg = AppendTextArea_Log(app, msg, usingTimeStamp)
            if app.Switch_Log.Value == "off"
                return;
            end

            if ~exist('usingTimeStamp','var')
                usingTimeStamp = false;
            end

            if usingTimeStamp == true
                % Append message on text area
                timeMsg =string(datetime("now")).char();
                Msg = [timeMsg, ' ', char(msg)];
            else
                timeMsg = '';
                Msg = char(msg);
            end
            if isempty(app.TextArea_Log.Value{1})
                app.TextArea_Log.Value={Msg};
            else
                app.TextArea_Log.Value = [app.TextArea_Log.Value;{Msg}];
            end

            % scroll to bottom auto
            scroll(app.TextArea_Log, "bottom");
        end

        %========================== DATA UPDATE ==========================

        function status = DataConstructor(app, mov, opts, rt)
            % This function load data to app workspace
            % =============== VALIDATE DATA FORMATION ================
            if isempty(opts) || isempty(mov), status = -1; return; end

            if isempty(rt), rt = 1:opts.frames; end

            if opts.frames < 2
                uialert(app.Reg3DV400daVinciUIFigure,...
                    app.region.chmap.UNSUPPORTED_FEW_FRAMES,...
                    app.region.chmap.WARNING,"Icon","warning");
                status = -2;
                return;
            end

            if opts.slices < 5
                uialert(app.Reg3DV400daVinciUIFigure,...
                    app.region.chmap.UNSUPPORTED_FEW_LAYERS,...
                    app.region.chmap.WARNING,"Icon","warning");
                status = -3;
                return;
            end

            % =========================================================
            % reset the data in memory
            InitData(app);

            PanelWaiting(app);  %

            app.img_cur = regmov(mov, opts, rt);

            % set data loading flag
            app.loaded_flag = true;

            % init start volume at channel 1
            app.c_order = opts.cOrder;
            app.cidx_cur = false(1, app.img_cur.MetaData.channels);
            app.cidx_cur(1) = true;
            switch app.c_order(1)
                case "r"
                    app.pcolor.normal = constdef.COLOR_IMAGING_RED;
                case "g"
                    app.pcolor.normal = constdef.COLOR_IMAGING_GREEN;
                otherwise
            end

            % init view parameters
            app.sidx_cur = 1;
            app.fidx_cur = 1;
            app.opt_crop.xy = [1, 1; ...
                app.img_cur.MetaData.width, app.img_cur.MetaData.height];
            app.opt_crop.f = 1:app.img_cur.MetaData.frames;
            app.opt_crop.z = [1, app.img_cur.MetaData.slices];
            app.fps.base = opts.frames/(rt(end)-rt(1)); % real time fps
            app.fps.fold = 1;                           % play speed fold

            % init registration parameters
            if isscalar(app.c_order)
                app.fixdef = struct("Sampling", ["mean", "1"], ...
                    "Channel",  "g");
            else
                app.fixdef = struct("Sampling", ["mean", "1"], ...
                    "Channel",  "r");
            end

            app.frames_reg = ["1:end", "1"];

            UpdateRegOptionsAfterLoading(app);

            % update player timer
            UpdatePlayerTimer(app, opts.frames, app.fps.base);
            app.mode_merge = "NORMAL";

            PanelRecovered(app);

            status = 0;
        end

        function Free(app)
            % reset the locker
            app.viewer_locker = true;

            % stop timers
            RemoveTimer(app);

            % remove ROIs
            delete(app.hdle_ROI);

            % remove scale bar
            delete(app.sbar);

            % close all sub handles
            CloseAllSubApp(app);

            % unload kernel
            delete(app.kernel);

            % unload external library
            Register.UnloadExternalLib();

            % reset the load flag to avoid bad call back
            app.loaded_flag = false;

            % clear history manager
            delete(app.ohmgr);

            % clean temporary folder
            switch app.opt_app.BufferCleanTrigger
                case "EXIT"
                    mpimg.clean_temporary_folder(app.opt_app.Capacity);
                    mpimgs.clean_temporary_folder(app.opt_app.Capacity);
                otherwise
            end

            % recover recycle status
            recycle(app.recycle_old_status);

            % delete this at last
            delete(app);
        end

        function ChangeRunModal(app)
            % modify storage location (memory or disk)
            % no effect on "real time" calculation node

            PanelWaiting(app);
            % this function changes the running modal by memmap_flag

            % change the distribution mode
            app.ohmgr.IsDistributed = app.distrib_flag;

            PanelRecovered(app);

            RefreshViewer(app);
        end

        function UpdateCurrentSlice(app, shift)
            % This function update the current slice: app.sliceimg_cur for
            % viewing, where the data type is m*n*3 true color uint16 image
            % if loading the data info
            arguments
                app
                shift (1,3) double = [0, 0, 0]      % dx, dy, da
            end
            
            if app.loaded_flag == true
                if any(shift ~= [0, 0, 0])
                    % structured channel as slice_s, functional channel as
                    % slice_f
                    if app.mode_zproj == "none"
                        if ~isempty(app.img_cur)
                            [~, cidx] = ismember(app.opt_reg.Options.FC, ...
                                app.img_cur.MetaData.cOrder);
                            slice_f = app.img_cur.Movie(:, :, cidx, ...
                                app.sidx_cur, app.fidx_cur);
                            slice_f = TransformBCG(slice_f, app.graphics_opts);
                            [~, cidx] = ismember(app.opt_reg.Options.SC, ...
                                app.img_cur.MetaData.cOrder);
                            slice_s = app.img_cur.Movie(:, :, cidx, ...
                                app.sidx_cur, app.fidx_cur);
                            slice_s = TransformBCG(slice_s, app.graphics_opts);
                        end
                    else
                        if ~isempty(app.img_zproj.cur)
                            [~, cidx] = ismember(app.opt_reg.Options.FC, ...
                                app.img_cur.MetaData.cOrder);
                            slice_f = app.img_zproj.cur(:, :, ...
                                cidx, app.fidx_cur);
                            slice_f = TransformBCG(slice_f, app.graphics_opts);
                            [~, cidx] = ismember(app.opt_reg.Options.SC, ...
                                app.img_cur.MetaData.cOrder);
                            slice_s = app.img_zproj.cur(:, :, ...
                                cidx, app.fidx_cur);
                            slice_s = TransformBCG(slice_s, app.graphics_opts);
                        end
                    end
                    % do imwarp for slice_s motion
                    rot = [cosd(shift(3)),  sind(shift(3)); ...
                           -sind(shift(3)), cosd(shift(3))];
                    RA = imref2d(size(slice_f), app.img_cur.MetaData.xRes, ...
                                                app.img_cur.MetaData.yRes);
                    tform = rigid2d(rot, shift(1:2));
                    slice_f_tf = imwarp(slice_f, RA, tform, "linear", ...
                        "FillValues",prctile(slice_f, 5, "all"), ...
                        "OutputView", RA);
                    switch app.opt_reg.Options.FC
                        case "g"
                            slice_cur = CompositeC_new(slice_s, slice_f_tf, [], ...
                                app.pcolor.normal);
                        case "r"
                            slice_cur = CompositeC_new(slice_f_tf, slice_s, [], ...
                                app.pcolor.normal);
                        otherwise
                    end
                else % if no shift
                    switch app.mode_merge
                        case "NORMAL"
                            % only normal image need view
                            if all(app.c_order(app.cidx_cur)=="r")
                                % red view
                                if app.mode_zproj == "none"
                                    slice_s = app.img_cur.Movie(:, :, ...
                                        app.cidx_cur, app.sidx_cur, app.fidx_cur);
                                else
                                    slice_s = app.img_zproj.cur(:, :, ...
                                        app.cidx_cur, app.fidx_cur);
                                end
                                slice_s = TransformBCG(slice_s, app.graphics_opts);
                                slice_f = [];
                                slice_b = [];
                            elseif all(app.c_order(app.cidx_cur)=="g")
                                % green view
                                if app.mode_zproj == "none"
                                    slice_f = app.img_cur.Movie(:, :, ...
                                        app.cidx_cur, app.sidx_cur, app.fidx_cur);
                                else
                                    slice_f = app.img_zproj.cur(:, :, ...
                                        app.cidx_cur, app.fidx_cur);
                                end
                                slice_s = [];
                                slice_f = TransformBCG(slice_f, app.graphics_opts);
                                slice_b = [];
                            elseif all(app.c_order(app.cidx_cur)==["r","g"]) ...
                                    || all(app.c_order(app.cidx_cur)==["g","r"])
                                % merge dual color
                                if app.mode_zproj == "none"
                                    slice_s = app.img_cur.Movie(:, :, ...
                                        app.c_order=="r", app.sidx_cur, app.fidx_cur);
                                    slice_f = app.img_cur.Movie(:, :, ...
                                        app.c_order=="g", app.sidx_cur, app.fidx_cur);
                                else
                                    slice_s = app.img_zproj.cur(:, :, ...
                                        app.c_order=="r", app.fidx_cur);
                                    slice_f = app.img_zproj.cur(:, :, ...
                                        app.c_order=="g", app.fidx_cur);
                                end
                                slice_s = TransformBCG(slice_s, app.graphics_opts);
                                slice_f = TransformBCG(slice_f, app.graphics_opts);
                                slice_b = [];
                            else
                            end
                            slice_cur = CompositeC_new(slice_s, slice_f, slice_b, ...
                                app.pcolor.normal);
                        case "FUSED"
                            % fuse template image and current channel
                            if app.mode_zproj == "none"
                                slice_s = app.refvol.RefVol(:, :, app.sidx_cur);
                                slice_f = app.img_cur.Movie(:, :, app.cidx_cur, ...
                                    app.sidx_cur, app.fidx_cur);
                            else
                                slice_s = app.img_zproj.ref;
                                slice_f = app.img_zproj.cur(:, :, app.cidx_cur, ...
                                    app.fidx_cur);
                            end

                            slice_s = TransformBCG(slice_s, app.graphics_opts);
                            slice_f = TransformBCG(slice_f, app.graphics_opts);
                            slice_b = [];
                            slice_cur = CompositeC_new(slice_s, slice_f, slice_b, ...
                                app.pcolor.fused);
                        otherwise
                    end 
                end

                app.simg_cur = slice_cur;
            else
                app.simg_cur = [];
            end
        end

        function UpdateCurrentVolume(app)
            % This function update the current volume: app.sliceimg_cur for
            % viewing, where the data type is m*n*p*3 true color uint16 image
            % if loading the data info
            if app.loaded_flag == true
                % calculate rescale resolution
                rs = min(app.img_cur.MetaData.xRes, app.img_cur.MetaData.yRes);
                sc_xyz = [app.img_cur.MetaData.xRes, app.img_cur.MetaData.yRes, ...
                    app.img_cur.MetaData.zRes]./rs;

                switch app.mode_merge
                    case "NORMAL"
                        % only current image need view
                        if all(app.c_order(app.cidx_cur)=="r")
                            % red view
                            volume_r = app.img_cur.Movie(:, :, ...
                                app.cidx_cur, :, app.fidx_cur);
                            volume_r = TransformBCG(volume_r, app.graphics_opts);
                            % resize for better viewing effort
                            volume_r = imresize3(volume_r, "Scale", sc_xyz, ...
                                "Method","linear");
                            volume_g = [];
                            volume_b = [];
                        elseif all(app.c_order(app.cidx_cur)=="g")
                            % green view
                            volume_g = app.img_cur.Movie(:, :, ...
                                app.cidx_cur, :, app.fidx_cur);
                            volume_r = [];
                            volume_g = TransformBCG(volume_g, app.graphics_opts);
                            volume_g = imresize3(volume_g, "Scale", sc_xyz, ...
                                "Method","linear");
                            volume_b = [];
                        elseif all(app.c_order(app.cidx_cur)==["r","g"]) ...
                                || all(app.c_order(app.cidx_cur)==["g","r"])
                            % merge dual color
                            volume_r = app.img_cur.Movie(:, :, ...
                                    app.c_order=="r", :, app.fidx_cur);
                                volume_g = app.img_cur.Movie(:, :, ...
                                    app.c_order=="g", :, app.fidx_cur);
                            volume_r = TransformBCG(volume_r, app.graphics_opts);
                            volume_r = imresize3(volume_r, "Scale", sc_xyz, ...
                                "Method","linear");
                            volume_g = TransformBCG(volume_g, app.graphics_opts);
                            volume_g = imresize3(volume_g, "Scale", sc_xyz, ...
                                "Method","linear");
                            volume_b = [];
                        else
                        end
                        app.vimg_cur = CompositeC_new(volume_r, volume_g, volume_b, ...
                            app.pcolor.normal);
                    case "FUSED"
                        volume_r = app.refvol.RefVol;
                        volume_g = app.img_cur.Movie(:, :, app.cidx_cur, ...
                            :, app.fidx_cur);
                        volume_r = TransformBCG(volume_r, app.graphics_opts);
                        volume_r = imresize3(volume_r, "Scale", sc_xyz, ...
                            "Method","linear");
                        volume_g = TransformBCG(volume_g, app.graphics_opts);
                        volume_g = imresize3(volume_g, "Scale", sc_xyz, ...
                            "Method","linear");
                        volume_b = [];
                        app.vimg_cur = CompositeC_new(volume_r, volume_g, volume_b, ...
                            app.pcolor.fused);
                    otherwise
                end
            else
                app.vimg_cur = [];
            end
        end

        function UpdateMultiplyROIs(app, dview)
            if app.loaded_flag == true && app.masked_flag == true
                switch dview
                    case {'XY', 'Z'}
                        % most time occupied
                        % refresh only if cell segmentator exist because it
                        % holds the ROIs controller
                        if ~isempty(app.CS_handle) && isvalid(app.CS_handle)
                            app.CS_handle.UpdateMultiplyROIs(app.sidx_cur);
                        end
                    otherwise
                end
            end
        end

        function DrawUtilsComponents(app, dview)
            if ~isempty(app.simg_cur)
                % draw information text
                if ~isempty(app.text_time.handle) && isvalid(app.text_time.handle)
                    app.text_time.handle.String = ...
                        app.text_time.text(app.fidx_cur);
                end
                if ~isempty(app.text_meta.handle) && isvalid(app.text_meta.handle)
                    app.text_meta.handle.String = ...
                        app.text_meta.text{app.fidx_cur};
                end

                % ROI display supported only on 2D view mode
                if app.mode_dview == "2D"
                    % draw ROIs(multiply)
                    UpdateMultiplyROIs(app, dview);
                end
            end
        end

        function SourceZProjection(app, frame, method)
            % setup the z projection method
            if ~exist("method","var") || isempty(method)
                method = "max";
            else
                if method == "none"
                    % no update and quit immediately
                    return;
                end
            end

            app.img_cur.ZProjMethod = method;

            % call z projection property
            if ~exist("frame","var") || isempty(frame)
                app.img_zproj.cur = app.img_cur.MovieZProj;
            else
                % increasing update
                app.img_zproj.cur(:,:,:,frame) = ...
                    app.img_cur.tcrop(frame).MovieZProj;
            end

            % update the refvol z projection
            app.img_zproj.ref = Projection(app.refvol.RefVol, method, 3);
        end

        function VolumePostureAdjustment(app, op)
            %note that flip does not change X or Y size, but rotate will
            switch op
                case "fliplr"
                    app.img_cur.fliplr();
                    if app.mode_zproj ~= "none"
                        app.img_zproj.cur = fliplr(app.img_zproj.cur);
                    end
                    app.img_zproj.ref = fliplr(app.img_zproj.ref);
                case "flipud"
                    app.img_cur.flipud();
                    if app.mode_zproj ~= "none"
                        app.img_zproj.cur = flipud(app.img_zproj.cur);
                    end
                    app.img_zproj.ref = flipud(app.img_zproj.ref);
                case "rot90"
                    app.img_cur.rot90();
                    if app.mode_zproj ~= "none"
                        app.img_zproj.cur = rot90(app.img_zproj.cur);
                    end
                    app.img_zproj.ref = rot90(app.img_zproj.ref);
                case "-rot90"
                    app.img_cur.rot90(3);
                    if app.mode_zproj ~= "none"
                        app.img_zproj.cur = rot90(app.img_zproj.cur, 3);
                    end
                    app.img_zproj.ref = rot90(app.img_zproj.ref, 3);
                otherwise
            end
        end

        function status = UpdateRegion(app, lang)
            if ismember(lang, ["zh_CN", "en_US"])
                app.region.lang = lang;
                app.region.chmap = readstruct(['lang',filesep,char(lang),'.xml'], ...
                    "FileType","xml");
                status = 0;
            else
                status = -1;
            end
        end

        function r = RegisterFrameAdapter(app)
            % This adapter translate the reg-frame to kernel inner format
            switch app.opt_reg.Algorithm
                case "MANREG"
                    r = str2double(app.frames_reg(2));
                otherwise
                    r = app.frames_reg(1).replace("end", ...
                        string(app.img_cur.MetaData.frames));
                    r = str2num(r); %#ok<ST2NM>
            end
        end

        function val = GetPixelValueAt(app, x, y)
            arguments
                app (1,1)   Register
                x   (1,1)   double  {mustBeInteger, mustBePositive}
                y   (1,1)   double  {mustBeInteger, mustBePositive}
            end

            if app.mode_zproj == "none"
                val = app.img_cur.Movie(y, x, app.cidx_cur,...
                    app.sidx_cur, app.fidx_cur);
            else
                val = app.img_zproj.cur(y, x, app.cidx_cur, app.fidx_cur);
            end
        end

        function slice_img = GetSliceImgAt(app, z, t)
            arguments
                app (1,1) Register
                z   (1,1) double    = app.sidx_cur
                t   (1,1) double    = app.fidx_cur
            end

            if app.mode_zproj == "none"
                slice_img = app.img_cur.Movie(:, :, app.cidx_cur, z, t);
            else
                slice_img = app.img_zproj.cur(:, :, app.cidx_cur, t);
            end
        end

        function volume_img = GetVolumeImgAt(app, t)
            arguments
                app (1,1)   Register
                t   (1,1)   double      = app.fidx_cur
            end

            volume_img = squeeze(app.img_cur.Movie(:, :, app.cidx_cur, :, t));
        end
        
        %================= USER INTERFSACE OPTIMIZATION ==================
        
        function PanelWaiting(app)
            % set the pointer for better visual effort
            set(app.Reg3DV400daVinciUIFigure,'Pointer','watch');
            drawnow
        end

        function PanelRecovered(app)
            % set the pointer back
            % why need user mouse operation trigger?
            set(app.Reg3DV400daVinciUIFigure,'Pointer','arrow');
            drawnow
        end

        %==================== SYSTEM PRESET SET/GET ======================
       
        function SavePreset(app,filename)
            % generate a struct contain part of stable variables as preset
            preset_save = app.opt_reg.Options;
            preset_save.alg = app.opt_reg.Algorithm;
            preset_save.mode = app.opt_reg.Mode;
            writestruct(preset_save,filename,"FileType","xml");
            GenSysMsg(app, ["CONFIGURATION_EXPORTED",""]);
        end

        function preset_load = LoadPreset(app, filename)
            preset_load = readstruct(filename,"FileType","xml");

            % check the fields
            fields_name = fieldnames(preset_load);
            reg_fieldsname = fieldnames(app.opt_reg.Options);
            fields_apdx = {'alg'; 'mode'};
            if all(ismember(fields_name,union(reg_fieldsname,fields_apdx)))...
                    && numel(fields_name) == numel(reg_fieldsname) + numel(fields_apdx)
                % change the algorithm and mode at first
                app.opt_reg.Algorithm = preset_load.alg;
                app.opt_reg.Mode = preset_load.mode;

                % append other parameters
                for k = 1:numel(reg_fieldsname)
                    if strcmp(reg_fieldsname{k}, 'Hardware') == true
                        % skip hardware information(hardware independent)
                        continue;
                    end
                    app.opt_reg = set(app.opt_reg, reg_fieldsname{k}, ...
                        preset_load.(reg_fieldsname{k}));
                end
                
                GenSysMsg(app, ["CONFGURATION_LOADED",""]);
            else
                uialert(app.Reg3DV400daVinciUIFigure, ...
                    app.region.CONFIGURATION_CORRUPTED,...
                    app.region.ERROR, "Icon","error");
                preset_load = [];
                return;
            end
        end

        %===================== SYSTEM CONTROLLER =========================

        function UpdateSystemAfterLoading(app)
            % update panels status
            UpdatePanelsAfterLoading(app);

            % update viewer
            InitViewer(app);

            % refresh the slice viewer display and mode selection
            switch app.mode_dview
                case "2D"
                    SetSlider_Slice(app, 'on');
                    app.Menu_View2D.Checked = "on";
                    app.Menu_View3D.Checked = "off";
                case "3D"
                    SetSlider_Slice(app, 'freeze');
                    app.Menu_View3D.Checked = "on";
                    app.Menu_View2D.Checked = "off";
                otherwise
            end

            SetSlider_Time(app, [1, app.img_cur.MetaData.frames]);

            % unlock the display locker
            app.viewer_locker = false;

            RefreshViewer(app);
        end

        function LockComponentsBeforeRegistration(app)
            app.Menu_File.Enable = "off";
            app.Menu_Image.Enable = "off";
            app.Menu_Options.Enable = "off";
            app.Menu_CellSegmentor.Enable = "off";
            app.Spinner_Slice.Enable = "off";
            app.Slider_Slice.Enable = "off";
            app.Spinner_Time.Enable = "off";
            app.Slider_Time.Enable = "off";
            app.DropDown_Source.Enable = "off";
            app.Menu_RectangleROI.Enable = "off";
            app.Menu_CircleROI.Enable = "off";
            app.Menu_PolygonROI.Enable = "off";
            app.Menu_FreehandROI.Enable = "off";
            app.Menu_ExportLog.Enable = "off";
            app.Menu_ClearLog.Enable = "off";
            app.TextArea_Log.Enable = "off";
            app.Switch_Log.Enable = "off";
            app.Button_HistoryManager.Enable = "off";
            app.Tree_Operations.Enable = "off";
            app.TextArea_OperationSnapshot.Enable = "off";
            
            drawnow
        end

        function UnlockComponentsAfterRegistration(app)
            app.Menu_File.Enable = "on";
            app.Menu_Image.Enable = "on";
            app.Menu_Options.Enable = "on";
            app.Menu_CellSegmentor.Enable = "on";
            if app.mode_zproj == "none"
                app.Spinner_Slice.Enable = "on";
                app.Slider_Slice.Enable = "on";
            end
            app.Spinner_Time.Enable = "on";
            app.Slider_Time.Enable = "on";
            app.DropDown_Source.Enable = "on";
            app.Menu_RectangleROI.Enable = "on";
            app.Menu_CircleROI.Enable = "on";
            app.Menu_PolygonROI.Enable = "on";
            app.Menu_FreehandROI.Enable = "on";
            app.Menu_ExportLog.Enable = "on";
            app.Menu_ClearLog.Enable = "on";
            app.TextArea_Log.Enable = "on";
            app.Switch_Log.Enable = "on";
            app.Button_HistoryManager.Enable = "on";
            app.Tree_Operations.Enable = "on";
            app.TextArea_OperationSnapshot.Enable = "on";

            drawnow
        end

        function CloseSubApp(app, h_name) %#ok<INUSD>
            h = eval("app."+h_name+"_handle;");

            if ~isempty(h) && isvalid(h)
                h.Close();
            end
        end

        function CloseAllSubApp(app)
            for name = app.SUBAPP_SNAME
                CloseSubApp(app, name);
            end
        end

        function CloseDataRelatedSubApp(app)
            for name = app.SUBAPP_SNAME_DATA_RELATED
                CloseSubApp(app, name);
            end
        end

        function RefreshSubAppLanguage(app, h_name) %#ok<INUSD>
            h = eval("app."+h_name+"_handle;");

            if ~isempty(h) && isvalid(h)
                h.RefreshPanelLanguage();
            end
        end

        function RefreshSubAppStyle(app, h_name) %#ok<INUSD>
            h = eval("app."+h_name+"_handle;");

            if ~isempty(h) && isvalid(h)
                h.RefreshPanelStyle();
            end
        end

        function RefreshAllSubAppLanguage(app)
            for name = app.SUBAPP_SNAME
                RefreshSubAppLanguage(app, name);
            end
        end

        function RefreshAllSubAppStyle(app)
            for name = app.SUBAPP_SNAME
                RefreshSubAppStyle(app, name);
            end
        end

        function RegisterGo(app)
            % update button status
            app.Button_Run.BackgroundColor = [0.4, 1, 0];
            app.Button_Run.Text = app.region.chmap.IN_PROGRESS;
            drawnow

            % update controller arguments
            app.kernel.RegisterOptions = app.opt_reg;
            app.kernel.Distributed = app.distrib_flag;
            regfr = RegisterFrameAdapter(app);

            % lock Panels
            LockComponentsBeforeRegistration(app);
            
            % generate new image as destination 
            img_reg = app.img_cur.copy();   % deep copy

            % update template and run
            app.refvol = regtmpl(app.img_cur, app.fixdef);

            app.viewer_locker = true;     % lock display callbacks
            app.kernel.run(app.img_cur, img_reg, app.refvol, regfr);
            app.img_cur = img_reg;
            
            % !!! add history node
            [args, others] = app.TakeSystemBasicSnap();
            app.ohmgr.AddNode(constdef.OP_REGISTER, args, ...
                "Data",app.img_cur, "Transform",app.img_cur.Transformation, ...
                "Others",others);
            app.viewer_locker = false;      % unlock display callbacks

            % append the merge menu
            if isempty(app.Menu_MergeSource) ...
                    || ~isvalid(app.Menu_MergeSource)
                app.pcolor.fused = constdef.COLOR_IMAGING_RED_CYAN;
                AppendMergeChannelMenu(app, "off");
            end

            % Synchronize z projection
            SourceZProjection(app, [], app.mode_zproj);

            RefreshViewer(app);

            % update main panel appearance
            app.Button_Run.Value = false;
            app.Button_Run.Text = app.region.chmap.RUN;
            app.Button_Run.BackgroundColor = [0.96,0.96,0.96];
            app.DropDown_Source.Items = [app.region.chmap.NORMAL, ...
                app.region.chmap.FUSED];    % append fused item

            % unlock options
            app.Menu_RegEvaluate.Enable = "on";
            UnlockComponentsAfterRegistration(app);
        end

        function RegNotReadyWarn(app)
            if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                switch numel(app.c_order)
                    case 1
                        app.RS_handle.FontBlink("CheckBox_Lock_" + ...
                            ["Global","Local","MGlobal"]);
                    case 2
                        app.RS_handle.FontBlink("CheckBox_Lock_" + ...
                            ["Global","Local","MGlobal","LTGlobal"]);
                    otherwise
                end
            end
            app.Button_Run.Value = false;
            GenSysMsg(app, ["REGISTRATION_SOLUTION_ERROR",""]);
        end

        function RemoveTimer(app)
            if ~isempty(app.regdaemon_timer)
                stop(app.regdaemon_timer);
                delete(app.regdaemon_timer);
            end
            if ~isempty(app.player_timer)
                stop(app.player_timer);
                delete(app.player_timer);
            end
            if ~isempty(app.progress_timer)
                stop(app.progress_timer);
                delete(app.progress_timer);
            end
        end

        function status = GenSysMsg(app, cmdstrs, out, timestamp)
            arguments
                app         (1,1)   Register
                cmdstrs     (1,2)   string
                out         (1,1)   logical     = true
                timestamp   (1,1)   logical     = true
            end

            if app.Switch_Log.Value == "off"
                status = size(app.sysmsg, 1);
                return;
            end

            % record the message
            if timestamp == true
                app.sysmsg = [app.sysmsg; ...
                    cmdstrs(1), cmdstrs(2), string(datetime("now"))];
            else
                app.sysmsg = [app.sysmsg; ...
                    cmdstrs(1), cmdstrs(2), ""];
            end

            % apply output
            if out == true
                t_msg = AppendTextArea_Log(app, ...
                    app.region.chmap.(cmdstrs(1))+cmdstrs(2),...
                    timestamp);
                app.sysmsg(end, 3) = string(t_msg);
            end

            status = size(app.sysmsg, 1);
        end

        function fid = ValidateUpdate(app)
            % This function read the update profile and set options
            fid = fopen(['startup_plugins', filesep, ...
                'update_setting.ini'], "r");

            if fid == -1
                uialert(app.Reg3DV400daVinciUIFigure,...
                    app.region.chmap.UNABLE_ACCESS_UPDATE_SETTING,...
                    app.region.chmap.WARNING, "Icon","warning");
                return;
            end
            setting_profile = textscan(fid, "%s",'Delimiter','\n');
            fclose(fid);
            setting_profile = string(setting_profile{1});

            for k = 1:numel(setting_profile)
                if setting_profile(k).replace(" ","") == "" ...
                        || iscomment(setting_profile(k))
                    % skip this line
                    continue;
                end
                key = setting_profile(k).extractBefore(" = ");

                switch upper(key)
                    case "REPOSITORY_PC"
                        if ispc()
                            value = setting_profile(k).extractAfter(" = ");
                            if isfolder(value)
                                app.opt_update.repository = value;
                            else
                                warning(app.region.chmap.INVALID_UPDATE_REPOSITORY);
                            end
                        end
                    case "REPOSITORY_UNIX"
                        if isunix()
                            value = setting_profile(k).extractAfter(" = ");
                            if isfolder(value)
                                app.opt_update.repository = value;
                            else
                                warning(app.region.chmap.INVALID_UPDATE_REPOSITORY);
                            end
                        end
                    case "AUTO_CLEAN"
                        value = lower(setting_profile(k).extractAfter(" = "));
                        if value == "0" || value == "off" || value == "false"
                            app.opt_update.auto_clean = false;
                        elseif value == "1" || value == "on" || value == "true"
                            app.opt_update.auto_clean= true;
                        else
                            warning(app.region.chmap.UNRECOGNIZED_UPDATE_IDENTIFIER);
                        end
                    otherwise
                        if ~ismissing(key)
                            warning(app.region.chmap.UNRECOGNIZED_UPDATE_IDENTIFIER);
                        end
                end
            end

            % set the update image visiable
            [status, ~, ~, ch] = acquire_update(app.opt_update.repository, Register.VER);

            switch status
                case "UPDATABLE"
                    pathToMLAPP = fileparts(mfilename('fullpath'));
                    switch upper(ch)
                        case "RELEASE"
                            app.Image_Update.ImageSource = ...
                                fullfile(pathToMLAPP, 'sources', 'update_release.png');
                            app.Image_Update.Visible = "on";
                        case "PREVIEW"
                            if ismember(app.opt_app.UpdateChannel, ["PRE", "ALL"])
                                app.Image_Update.ImageSource = ...
                                    fullfile(pathToMLAPP, 'sources', 'update_preview.png');
                                app.Image_Update.Visible = "on";
                            else
                                app.Image_Update.Visible = "off";
                            end
                        case "DEBUG"
                            if app.opt_app.UpdateChannel == "ALL"
                                app.Image_Update.ImageSource = ...
                                    fullfile(pathToMLAPP, 'sources', 'bug.png');
                                app.Image_Update.Visible = "on";
                            else
                                app.Image_Update.Visible = "off";
                            end
                        otherwise
                    end
                case "ACCESS_FAILED"
                    app.Image_Update.Visible = "off";
                    uialert(app.Reg3DV400daVinciUIFigure,...
                        app.region.chmap.UNABLE_ACCESS_SERVER, ...
                        app.region.chmap.ERROR, "Icon","error");
                    return;
                otherwise
                    app.Image_Update.Visible = "off";
            end

            function r = iscomment(str, marker)
                if ~exist("marker", "var")
                    marker = "#";
                end
                % comment means the marker is header
                r = (string(str).extractBefore(marker).replace(" ","") == "");
            end
        end

        function HoldWindowOn(app)
            % Use this function after uigetfile/uiputfile/uigetdir/uiopen
            % ... file operations, etc
            app.Reg3DV400daVinciUIFigure.WindowStyle = "alwaysontop";
            pause(0.5);
            app.Reg3DV400daVinciUIFigure.WindowStyle = "normal";
        end

        %================== SELF DEFINATION CALL BACK ====================
       
        function uROI_Action(app, ~, ~)
            UpdateViewerTitle(app);
        end

        function TimeText_ButtonDownFcn(app, event)
            switch event.Button
                case 1
                    % left button for motion tracking
                    % switch the moving status
                    app.text_time.ismoving = ~app.text_time.ismoving;
                case 2
                    % middle button for font setting
                    uisetfont(app.text_time.handle);
                case 3
                    % right button for color setting
                    uisetcolor(app.text_time.handle);
                otherwise
            end
        end

        function MetaText_ButtonDownFcn(app, event)
            switch event.Button
                case 1
                    % left button for motion tracking
                    % switch the moving status
                    app.text_meta.ismoving = ~app.text_meta.ismoving;
                case 2
                    uisetfont(app.text_meta.handle);
                case 3
                    uisetcolor(app.text_meta.handle);
                otherwise
            end
        end

        function Menu_RedSelected(app, ~)
            app.cidx_cur = ("r" == app.c_order);
            app.DropDown_Source.Enable = "on";
            switch app.mode_merge
                case "NORMAL"
                    app.pcolor.normal = constdef.COLOR_IMAGING_RED;
                otherwise
                    % keep
            end

            if numel(app.c_order) == 2
                app.Menu_Red.Checked = "on";
                app.Menu_Green.Checked = "off";
                app.Menu_Red_Green.Checked = "off";
            end
            RefreshViewer(app);
        end

        function Menu_GreenSelected(app, ~)
            app.cidx_cur = ("g" == app.c_order);
            app.DropDown_Source.Enable = "on";
            switch app.mode_merge
                case "NORMAL"
                    app.pcolor.normal = constdef.COLOR_IMAGING_GREEN;
                otherwise
                    % keep
            end

            if numel(app.c_order) == 2
                app.Menu_Red.Checked = "off";
                app.Menu_Green.Checked = "on";
                app.Menu_Red_Green.Checked = "off";
            end
            RefreshViewer(app);
        end

        function Menu_Red_GreenSelected(app, ~)
            app.cidx_cur = true(1, numel(app.c_order));
            app.DropDown_Source.Enable = "off";
            switch app.mode_merge
                case "NORMAL"
                    app.pcolor.normal = constdef.COLOR_IMAGING_RED_GREEN;
                otherwise
                    % keep
            end

            if numel(app.c_order) == 2
                app.Menu_Red.Checked = "off";
                app.Menu_Green.Checked = "off";
                app.Menu_Red_Green.Checked = "on";
            end
            RefreshViewer(app);
        end

        function Menu_MergeRBSelected(app, ~)
            app.Menu_MergeRB.Checked = "on";
            app.Menu_MergeGR.Checked = "off";

            app.pcolor.fused = constdef.COLOR_IMAGING_RED_CYAN;

            RefreshViewer(app);
        end

        function Menu_MergeGRSelected(app, ~)
            app.Menu_MergeRB.Checked = "off";
            app.Menu_MergeGR.Checked = "on";

            app.pcolor.fused = constdef.COLOR_IMAGING_GREEN_MAGENTA;

            RefreshViewer(app);
        end

        function Menu_AddCellSelected(app, ~)
            % call nuclear control bot to add new circle
            if ~isempty(app.CS_handle) && isvalid(app.CS_handle)
                app.CS_handle.AddCell();
            end
        end

        function Menu_RefreshLabelSelected(app, ~)
            % call nuclear control bot to refresh label
            if ~isempty(app.CS_handle) && isvalid(app.CS_handle)
                app.CS_handle.RefreshLabel();
            end
        end

        function Menu_AnomalyDetectionSelected(app, ~)
            % call nuclear control bot to do anomaly detection
            if ~isempty(app.CS_handle) && isvalid(app.CS_handle)
                app.CS_handle.AnomalyDetection();
            end
        end

        function ModifySegmentArea(app, src, evt)
            bdbox_index = src.UserData;
            if evt.EventName == "ROIMoved"
                app.opt_seg.bdbox(bdbox_index, :) = ...
                    [ceil(src.Vertices(1,:)+0.5), 1, ...
                     ceil(src.Vertices(3,1)-src.Vertices(2,1)-1), ...
                     ceil(src.Vertices(2,2)-src.Vertices(1,2)-1), ...
                     app.img_cur.MetaData.slices-1];
            end
        end

        function SimpleStat_CallBack(app, ~, ~)
            % this function does simple statistics, export by message box
            if isempty(app.hdle_ROI) || ~isvalid(app.hdle_ROI)
                uialert(app.Reg3DV400daVinciUIFigure,...
                    app.region.chmap.CONFIRM_ROI_RANGE,...
                    app.region.chmap.WARNING, "Icon","warning");
            else
                mask = createMask(app.hdle_ROI);
                slice_img = GetSliceImgAt(app);
                data = double(slice_img(mask));
                stat = regionprops("table", mask, "Area","Perimeter");

                % some stat
                data_min = min(data);
                data_max = max(data);
                data_mean = mean(data);
                data_median = median(data);
                data_mode = mode(data);
                data_std = std(data);
                area_size = stat.Area*app.img_cur.MetaData.xRes ...
                    * app.img_cur.MetaData.yRes;    % um^2 as usual
                perimeter_length = stat.Perimeter*sqrt(app.img_cur.MetaData.xRes ...
                    * app.img_cur.MetaData.yRes);   % um as usual

                msg = sprintf("%s: %.2f\n" + ...
                              "%s: %.2f\n" + ...
                              "%s: %.2f\n" + ...
                              "%s: %.2f\n" + ...
                              "%s: %.2f\n" + ...
                              "%s: %.2f\n" + ...
                              "%s: %.2f um\n" + ...
                              "%s: %.2f um^2", ...
                              app.region.chmap.INTENSITY_MIN, data_min, ...
                              app.region.chmap.INTENSITY_MAX, data_max, ...
                              app.region.chmap.INTENSITY_MEAN, data_mean, ...
                              app.region.chmap.INTENSITY_MEDIAN, data_median, ...
                              app.region.chmap.INTENSITY_MODE, data_mode, ...
                              app.region.chmap.INTENSITY_STD, data_std, ...
                              app.region.chmap.AREA_PERIMETER, perimeter_length, ...
                              app.region.chmap.AREA_SIZE, area_size);

                uialert(app.Reg3DV400daVinciUIFigure, msg, ...
                    app.region.chmap.INFORMATION, "Icon", "info");
            end
        end

        function FindTemplateAuto_CallBack(app, ~, ~)
            upd = uiprogressdlg(app.Reg3DV400daVinciUIFigure, ...
                "Indeterminate","on", "Message",app.region.chmap.TEMPLATE_VOLUME_SEARCHING, ...
                "Icon","info");

            % estimate template index on current channel
            if ~isempty(app.img_cur)
                r = app.img_cur.ETmplIdx;
            else, delete(upd); return;
            end

            if r ~= 0
                % shift slider to given index
                app.Slider_Time.Value = r;

                % Synchronize the time spinner
                app.Spinner_Time.Value = r;
                app.fidx_cur = r;
                app.frames_reg(2) = string(r);

                % update current refvol
                app.fixdef.Sampling(2) = string(r);
                app.refvol = regtmpl(app.img_cur, app.fixdef);
                if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                    app.RS_handle.Refresh(app);
                end

                RefreshViewer(app, "T");
            end

            delete(upd);
        end

        function RestoreView2D_CallBack(app, ~, ~)
            %
            RefreshImagingRange2D(app);
        end
    end

    %========================== CLASS METHODS ============================
    
    methods(Static)
        function status = DeployParallelConfig()
            % load the parallel setting on different platform
            % note that app will not recover the configuration
            pinfo = struct2table(ver);
            par_flag = any(string(pinfo.Name).contains(...
                "Parallel Computing Toolbox"));

            if par_flag == true
                profs = string(parallel.listProfiles);  % >=R2022b
                if ismember("Reg3D_Local", profs) ...
                        || ismember("Reg3D_Server", profs)
                    if ismember("Reg3D_Local", profs) ...
                        && ismember("Reg3D_Server", profs)
                        error("Reg3D:invalidParallelConfiguration", ...
                            "Please keep only one parallel profile in " + ...
                            "'Cluster Profile Manager' manually.");
                    end
                    
                    if ismember("Reg3D_Local", profs) ...
                            && (parallel.defaultProfile()~="Reg3D_Local")
                        fprintf("Parallel profile changed: %s -> %s\n", ...
                            parallel.defaultProfile(), "Reg3D_Local");
                        parallel.defaultProfile("Reg3D_Local");
                    elseif ismember("Reg3D_Server", profs) ...
                            && (parallel.defaultProfile()~="Reg3D_Server")
                        fprintf("Parallel profile changed: %s -> %s\n", ...
                            parallel.defaultProfile(), "Reg3D_Server");
                        parallel.defaultProfile("Reg3D_Server");
                    end
                    status = 0;
                else
                    % get platform information and select the better
                    % configuration
                    pathToMLAPP = fileparts(mfilename('fullpath'));
                    local_setting_path = [pathToMLAPP, filesep, 'bin',filesep, ...
                        'Reg3D_Local.mlsettings'];
                    server_setting_path = [pathToMLAPP, filesep, 'bin',filesep, ...
                        'Reg3D_Server.mlsettings'];
                    disp("Auto deploy the parallel pool configuration at first use...");
                    sinfo = getCpuInfo();
                    if sinfo.NumProcessors > 16
                        if sinfo.OSType == "Linux"
                            disp("Reg3D_Server profile is enabled...");
                            % this machine is linux server
                            pf = parallel.importProfile(server_setting_path);
                            parallel.defaultProfile(pf); % set as default
                        else
                            % unknown work station
                            warning("Reg3D:unknownHPC",...
                                "Unknown HPC. Reg3D_Local profile enabled. " + ...
                                "You can modify it manually.");
                            pf = parallel.importProfile(local_setting_path);
                            parallel.defaultProfile(pf); % set as default
                        end
                    else
                        % no matter local machine OS
                        disp("Reg3D_Local profile is enabled...");
                        pf = parallel.importProfile(local_setting_path);
                        parallel.defaultProfile(pf); % set as default
                    end
                    disp("Done.");
                    status = 0;
                end
            else
                status = -1;
            end
        end

        function status = UnloadExternalLib()
            % all external calling must be included in try...catch...
            %% ND2 library
            try
                if libisloaded('Nd2ReadSdk')
                    unloadlibrary('Nd2ReadSdk');
                end
                if libisloaded('libNd2ReadSdk')
                    unloadlibrary('libNd2ReadSdk');
                end
            catch ME
                rethrow(ME);
            end

            %% Python Environment
            try
                terminate(pyenv);
            catch ME
                rethrow(ME);
            end

            status = 0;
        end

        function r = GetAppPath()
            r = fileparts(mfilename("fullpath"));
        end

        function vs = ReformatUpdateText(vs)
            arguments
                vs  (:,1)   string
            end

            % begin at text line
            for k = constdef.REG3D_UPDATE_FILE_TITLE_ROW_SIZE+1:numel(vs)
                type = vs(k).extractBetween("[","]");
                if isKey(constdef.UPDATE_TEXT_COLORMAP, lower(type))
                    vs(k) = sprintf("\color{%s}", ...
                        constdef.UPDATE_TEXT_COLORMAP(lower(type))) + vs(k);
                end
            end
        end

        function SetLoggerState(level)
            switch level
                case "WARNING"
                    warning("on");
                case "INFO"
                    warning("off");
                otherwise
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % NOTE: This app can only run on MATLAB version >= R2022a
            if isMATLABReleaseOlderThan("R2022a")
                disp("Your MATLAB version is older than R2022a, please update " + ...
                    "your MATLAB >= R2022a to continue.");
                delete(app);
                return;
            end

            pd = uiprogressdlg(app.Reg3DV400daVinciUIFigure, "Cancelable","off", ...
                "Icon","info","ShowPercentage","on","Title","System Initializing...", ...
                "Value",0);

            pd.Message = "Deploy environment configuration...";
            status_parpool = Register.DeployParallelConfig();
            if status_parpool == -1
                pd.Message = "Parallel configuration failed.";
                pd.Icon = "error";
                Free(app);
                return;
            end
            status_ienv = DeployEnvConfig(app);
            if status_ienv == -1
                pd.Message = "Environment configuration failed.";
                pd.Icon = "error";
                Free(app);
                return;
            end

            InitSysOptions(app);

            InitData(app);
            
            InitTimer(app);

            pd.Value = app.INIT_TIME_CONST(1);

            pd.Message = "Set system appearance...";
            UpdateAppProfiles(app);
            InitPanels(app);
            pd.Value = app.INIT_TIME_CONST(2);

            pd.Title = app.region.chmap.SYS_INITIALIZING;
            switch app.opt_app.AutoUpdate
                case "STARTUP"
                    pd.Message = app.region.chmap.VALIDATE_UPDATE;
                    pause(1);
                    ValidateUpdate(app);
                otherwise
            end
            pd.Value = app.INIT_TIME_CONST(3);

            pd.Icon = "success";
            pd.Message = app.region.chmap.COMPLETE;
            pd.Value = app.INIT_TIME_CONST(4);
            pause(1);
            
            delete(pd);

            start(app.regdaemon_timer);
            GenSysMsg(app, ["SYS_INITIALIZE_SUCCESS",""]);
        end

        % Menu selected function: Menu_LoadImage
        function Menu_LoadImageSelected(app, event)
            % init new reader
            imreader = ImageReader(app, app.distrib_flag);

            app.Reg3DV400daVinciUIFigure.Resize = "off";

            PanelWaiting(app);

            % loading data
            imreader.load("");

            PanelRecovered(app);

            app.Reg3DV400daVinciUIFigure.Resize = "on";

            % close all data related panel to avoid unsynchronize view
            CloseDataRelatedSubApp(app);

            opts = imreader.MetaData;
            rt = imreader.T;
            mov = imreader.Data;

            if isempty(rt), app.Menu_LoadTime.Enable = "on"; 
            else, app.Menu_LoadTime.Enable = "off";
            end
            
            % load variables to app workspace
            status = DataConstructor(app, mov, opts, rt);
            if status ~= 0
                return;
            end
            app.filename = imreader.FileName;

            % !!! add history node
            [args, others] = app.TakeSystemBasicSnap();
            app.ohmgr.AddNode(constdef.OP_LOAD, args, "Data",app.img_cur, ...
                "Others",others);

            % update the system after loading
            UpdateSystemAfterLoading(app);
        end

        % Menu selected function: Menu_SaveImageAs
        function Menu_SaveImageAsSelected(app, event)
            
            % get the base file name
            [~, fname, ~] = fileparts(app.filename);

            optrs_save = string([]);
            optrs = app.ohmgr.GetAllPreviousOperatorsAt();
            if ~isempty(optrs)
                optrs_save = optrs.join("-");
                fname = fname + "_" + optrs_save;
            end

            c_cur = app.c_order(app.cidx_cur);
            if c_cur == app.opt_reg.Options.SC
                ftype = "structured";
            else
                ftype = "functional";
            end

            app.Reg3DV400daVinciUIFigure.Resize = "off";
            PanelWaiting(app);

            if ~isempty(app.img_cur)
                fname_export = fname+"_"+ftype;
                meta_data = struct("ftype",     ftype, ...
                    "operation", optrs_save);

                imwriter = ImageWriter(app, fname_export);
                imwriter.save(app.img_cur, c_cur, meta_data);
            end

            if imwriter.Status == 0
                app.path_save = imwriter.Path;
                GenSysMsg(app, ["IMAGE_EXPORT_SUCCESS",""]);
            else
                app.path_save = "";
            end

            PanelRecovered(app);

            RefreshViewer(app);

            app.Reg3DV400daVinciUIFigure.Resize = "on";

            UpdateYScrollLim(app);
        end

        % Menu selected function: Menu_CropT
        function Menu_CropTSelected(app, event)
            % invoke the frames selector
            if isempty(app.FS_handle) || ~isvalid(app.FS_handle)
                app.FS_handle = FrameSelecter(app);
                waitfor(app.FS_handle);
            end

            % validate selected frames
            if numel(app.opt_crop.f) < 2
                uialert(app.Reg3DV400daVinciUIFigure, ...
                    app.region.chmap.FRAME_FEW, ...
                    app.region.chmap.WARNING, "Icon","warning");
                return;
            elseif numel(app.opt_crop.f) > app.img_cur.MetaData.frames
                uialert(app.Reg3DV400daVinciUIFigure, ...
                    app.region.chmap.FRAME_OVERFLOW, ...
                    app.region.chmap.WARNING, "Icon","warning");
                return;
            elseif numel(app.opt_crop.f) == app.img_cur.MetaData.frames
                return;
            end

            PanelWaiting(app);  %

            % crop the source movie
            app.img_cur =  app.img_cur.tcrop(app.opt_crop.f);

            % !!! add history node
            [args, others] = app.TakeSystemBasicSnap();
            app.ohmgr.AddNode(constdef.OP_CROP, args, ...
                "Data",app.img_cur, "CropDim","T", "Others",others);

            % crop text vector if is needed
            if ~isempty(app.text_time.text)
                app.text_time.text = app.text_time.text(app.opt_crop.f);
            end
            if ~isempty(app.text_meta.text)
                app.text_meta.text = app.text_meta.text(app.opt_crop.f);
            end

            % crop the z-projection
            if ~isempty(app.img_zproj.cur)
                [app.img_zproj.cur, ~] = ...
                    CropT(app.img_zproj.cur, app.opt_crop.f);
            end

            % reorder time axis
            app.fidx_cur = 1;
            SetSlider_Time(app, [1, app.img_cur.MetaData.frames]);

            % Synchronize z projection
            SourceZProjection(app, [], app.mode_zproj);

            % Synchronize registration frames
            app.frames_reg = ["1:end", "1"];
            GenSysMsg(app, ["RESET_REGISTRATION_FRAMES",""]);

            % Synchronize template frames
            tmpl = str2num(app.fixdef.Sampling(2)); %#ok<ST2NM>
            tmpl(~ismember(tmpl, app.opt_crop.f)) = [];
            if isempty(tmpl)
                app.fixdef.Sampling(2) = "1";
            else
                for k = 1:numel(tmpl)
                    tmpl(k) = tmpl(k) - ...
                        numel(setdiff(1:tmpl(k), app.opt_crop.f));
                end
                app.fixdef.Sampling(2) = string(tmpl).join(",");
            end
            if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                app.RS_handle.Refresh(app);
            end

            % update template
            app.refvol = regtmpl(app.img_cur, app.fixdef);

            GenSysMsg(app, ["T_CROPPED",""]);

            PanelRecovered(app);    %

            RefreshViewer(app);
        end

        % Menu selected function: Menu_About
        function Menu_AboutSelected(app, event)
            msg = sprintf("Register3D Graphics User Interface (RGUI) \n" + ...
                "Full function volume series Register \n" + ...
                "Version: %s (%s) %s @si-lab \n" + ...
                "Author: Weihan Li \n" + ...
                "E-mail: liweihan20@mails.ucas.ac.cn", ...
                app.VER, app.RELEASE_CODE, app.RELEASE_STATUS);
            uialert(app.Reg3DV400daVinciUIFigure, msg, ...
                app.region.chmap.INFORMATION, "Icon","info");
        end

        % Menu selected function: Menu_Documents
        function Menu_DocumentsSelected(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            help_file = [pathToMLAPP, filesep, 'help', filesep, '0.html'];
            
            if ~exist(help_file, "file")
                uialert(app.Reg3DV400daVinciUIFigure, ...
                    app.region.chmap.MISSING_HELP, ...
                    app.region.chmap.ERROR, "Icon","error");
                return;
            else
                web(help_file);
            end
        end

        % Value changed function: Slider_Slice
        function Slider_SliceValueChanged(app, event)
            value = round(app.Slider_Slice.Value);
            app.Slider_Slice.Value = value;

            % Synchronize the slice spinner
            app.Spinner_Slice.Value = value;
            app.sidx_cur = value;

            RefreshViewer(app, "Z");
        end

        % Value changed function: Slider_Time
        function Slider_TimeValueChanged(app, event)
            value = round(app.Slider_Time.Value);
            app.Slider_Time.Value = value;

            % Synchronize the time spinner
            app.Spinner_Time.Value = value;
            app.fidx_cur = value;
            app.frames_reg(2) = string(value);

            RefreshViewer(app, "T");
        end

        % Value changed function: Spinner_Slice
        function Spinner_SliceValueChanged(app, event)
            value = round(app.Spinner_Slice.Value);
            app.Spinner_Slice.Value = value;

            % Synchronize the slice slider
            app.Slider_Slice.Value = value;
            app.sidx_cur = value;
            
            RefreshViewer(app, "Z");
        end

        % Value changed function: Spinner_Time
        function Spinner_TimeValueChanged(app, event)
            value = round(app.Spinner_Time.Value);
            app.Spinner_Time.Value = value;

            % Synchronize the slice slider
            app.Slider_Time.Value = value;
            app.fidx_cur = value;
            app.frames_reg(2) = string(value);

            RefreshViewer(app, "T");
        end

        % Menu selected function: Menu_RectangleROI
        function Menu_RectangleROISelected(app, event)
            if ~isempty(app.hdle_ROI) && isvalid(app.hdle_ROI)
                delete(app.hdle_ROI);
            end
            app.hdle_ROI = drawrectangle(app.UIAxes_Slice);
            addlistener(app.hdle_ROI,'MovingROI',   @app.uROI_Action);
            addlistener(app.hdle_ROI,'DeletingROI', @app.uROI_Action);
        end

        % Menu selected function: Menu_CircleROI
        function Menu_CircleROISelected(app, event)
            if ~isempty(app.hdle_ROI) && isvalid(app.hdle_ROI)
                delete(app.hdle_ROI);
            end
            app.hdle_ROI = drawcircle(app.UIAxes_Slice);
            addlistener(app.hdle_ROI,'MovingROI',   @app.uROI_Action);
            addlistener(app.hdle_ROI,'DeletingROI', @app.uROI_Action);
        end

        % Menu selected function: Menu_PolygonROI
        function Menu_PolygonROISelected(app, event)
            if ~isempty(app.hdle_ROI) && isvalid(app.hdle_ROI)
                delete(app.hdle_ROI);
            end
            app.hdle_ROI = drawpolygon(app.UIAxes_Slice);
            addlistener(app.hdle_ROI,'MovingROI',   @app.uROI_Action);
            addlistener(app.hdle_ROI,'DeletingROI', @app.uROI_Action);
        end

        % Menu selected function: Menu_FreehandROI
        function Menu_FreehandROISelected(app, event)
            if ~isempty(app.hdle_ROI) && isvalid(app.hdle_ROI)
                delete(app.hdle_ROI);
            end
            app.hdle_ROI = drawfreehand(app.UIAxes_Slice);
            addlistener(app.hdle_ROI,'MovingROI',   @app.uROI_Action);
            addlistener(app.hdle_ROI,'DeletingROI', @app.uROI_Action);
        end

        % Menu selected function: Menu_SaveParameters
        function Menu_SaveParametersSelected(app, event)
            t = datetime('now','TimeZone','local','Format','yyMMdd');

            % get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));

            [name,path] = uiputfile({'*.reg3d', ...
                [app.region.chmap.REG3D_CONFIGURATION.char(), '(*.reg3d)']},...
                    app.region.chmap.SAVE_CONFIGURATION_FILE.char(),...
                    [pathToMLAPP, filesep, 'bin', filesep, char(t), '.reg3d']);
            if ~isequal(name,0)
                file_name = fullfile(path,name);
                SavePreset(app,file_name);
            end
        end

        % Menu selected function: Menu_LoadParameters
        function Menu_LoadParametersSelected(app, event)
            % get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));

            [name,path] = uigetfile({'*.reg3d', ...
                [app.region.chmap.REG3D_CONFIGURATION.char(), '(*.reg3d)']},...
                    app.region.chmap.READ_CONFIGURATION_FILE.char(),...
                    [pathToMLAPP, filesep, 'bin']);
            HoldWindowOn(app);

            if ~isequal(name,0)
                file_name = fullfile(path,name);
                preset = LoadPreset(app, file_name);
                if ~isempty(preset)
                    if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                        % refresh the RS_handle
                        app.RS_handle.Refresh(app);
                    end
                    % update playing menu setting
                    app.fps.fold = preset.fps_fold;
                    app.Menu_1F.Checked = "off";
                    app.Menu_5F.Checked = "off";
                    app.Menu_15F.Checked = "off";
                    switch app.fps.fold
                        case 1
                            app.Menu_1F.Checked = "on";
                        case 5
                            app.Menu_5F.Checked = "on";
                        case 15
                            app.Menu_15F.Checked = "on";
                    end
                    app.mode_loop = preset.key_mode;
                    app.Menu_Direct.Checked = "off";app.Menu_Loop.Checked = "off";
                    switch app.mode_loop
                        case "one-step"
                            app.Menu_Direct.Checked = "on";
                        case "loop"
                            app.Menu_Loop.Checked = "on";
                    end
                end
            end 
        end

        % Menu selected function: Menu_1F
        function Menu_1FSelected(app, event)
            app.fps.fold = 1;
            app.player_timer.Period = round(1/(app.fps.base*app.fps.fold), 3);
            app.Menu_1F.Checked = "on";
            app.Menu_5F.Checked = "off";
            app.Menu_15F.Checked = "off";
        end

        % Menu selected function: Menu_5F
        function Menu_5FSelected(app, event)
            app.fps.fold = 5;
            app.player_timer.Period = round(1/(app.fps.base*app.fps.fold), 3);
            app.Menu_1F.Checked = "off";
            app.Menu_5F.Checked = "on";
            app.Menu_15F.Checked = "off";
        end

        % Key press function: Reg3DV400daVinciUIFigure
        function Reg3DV400daVinciUIFigureKeyPress(app, event)
            key = event.Key;            % base key
            modifier = event.Modifier;  % modifier, can be any combination 
                                        % comes from ["control","alt","shift"] 

            if ~isempty(modifier) && all(modifier=="control")
                switch key
                    case {"a","A"}
                        % append frames to frames selector app
                        if ~isempty(app.FS_handle) && isvalid(app.FS_handle)
                            app.FS_handle.AppendFrame(app.fidx_cur);
                        end
                    case "tab"
                        % change the selected node in operation viewer
                        if ~isempty(app.img_cur)
                            app.ohmgr.SelectNextNode(true);
                            RefreshViewer(app, "T");
                        end
                    otherwise
                end
            elseif isempty(modifier)
                switch key
                    case {"d","D","rightarrow"}
                        NextFramePtr(app);
                        RefreshViewer(app, "T");
                    case {"a","A","leftarrow"}
                        PrevFramePtr(app);
                        RefreshViewer(app, "T");
                    case {"w","W","uparrow"}
                        NextSlicePtr(app);
                        RefreshViewer(app, "Z");
                    case {"s","S","downarrow"}
                        PrevSlicePtr(app);
                        RefreshViewer(app, "Z");
                    case "space"
                        if app.flag_playing
                            stop(app.player_timer);
                            app.flag_playing = false;
                        else
                            LoopN = app.Slider_Time.Limits(end) - app.fidx_cur;
                            switch app.mode_loop
                                case "one-step"
                                    if isempty(LoopN) || (LoopN==0)
                                        % reject start timer
                                        return;
                                    end
                                case "loop"
                                    LoopN = Inf;
                            end

                            app.player_timer.TasksToExecute = LoopN;
                            LockPlaySetting(app);

                            app.flag_playing = true;
                            start(app.player_timer);
                        end
                        RefreshViewer(app, "T");
                    otherwise
                end
            end
        end

        % Menu selected function: Menu_Direct
        function Menu_DirectSelected(app, event)
            app.Menu_Direct.Checked = true;
            app.Menu_Loop.Checked = false;
            app.mode_loop = "one-step";
            app.player_timer.TasksToExecute = app.Slider_Time.Limits(end) ...
                - app.fidx_cur;
        end

        % Menu selected function: Menu_Loop
        function Menu_LoopSelected(app, event)
            app.Menu_Direct.Checked = false;
            app.Menu_Loop.Checked = true;
            app.mode_loop = "loop";
            app.player_timer.TasksToExecute = Inf;
        end

        % Menu selected function: Menu_CropXY
        function Menu_CropXYSelected(app, event)
            % validate if ROI exist
            if ~isempty(app.hdle_ROI) && isvalid(app.hdle_ROI)
                roiXY_coor = ROI2Rectangle(app.hdle_ROI);
                % xy := [x1,y1;x2,y2] in pixel
                app.opt_crop.xy = ceil(roiXY_coor);

                PanelWaiting(app);  %

                % crop image
                if ~isempty(app.img_cur)
                    app.img_cur = ...
                        app.img_cur.vcrop("XY", app.opt_crop.xy');
                end

                % !!! add history node
                [args, others] = app.TakeSystemBasicSnap();
                app.ohmgr.AddNode(constdef.OP_CROP, args, ...
                    "Data",app.img_cur, "CropDim","XY", "Others",others);

                % update template definition
                app.refvol = regtmpl(app.img_cur, app.fixdef);

                % crop the z-projection
                if ~isempty(app.img_zproj.cur)
                    [app.img_zproj.cur, ~] = ...
                        CropXY(app.img_zproj.cur, app.opt_crop.xy);
                end

                % Synchronize z projection
                SourceZProjection(app, [], app.mode_zproj);

                % Synchronize mROI mask
                if ~isempty(app.mask_reg)
                    app.mask_reg = ...
                        app.mask_reg.crop("XY", app.opt_crop.xy');
                end

                % remove the exist ROIs if possible
                delete(app.hdle_ROI);

                % update the scalebar
                app.sbar.update(app.img_cur.MetaData);

                % reg3d post process
                GenSysMsg(app, ["XY_CROPPED",""]);

                PanelRecovered(app);

                % refresh current view
                RefreshImagingRange2D(app);
                RefreshViewer(app, "XY");
                UpdateYScrollLim(app);
            else
                uialert(app.Reg3DV400daVinciUIFigure,...
                    app.region.chmap.CONFIRM_ROI_RANGE,...
                    app.region.chmap.WARNING, "Icon","warning");
                return;
            end
        end

        % Menu selected function: Menu_CropZ
        function Menu_CropZSelected(app, event)
            % set z crop range dialog
            prompt = {['\fontsize{12} ', app.region.chmap.THICKNESS_RANGE.char()]};
            dlgtitle = app.region.chmap.CROP_Z.char();
            dims = [1 40];
            definput = {'1:end'};
            dlgopts.WindowStyle = 'normal';
            dlgopts.Interpreter = "tex";
            dlgout = inputdlg(prompt,dlgtitle,dims,definput,dlgopts);
            if isempty(dlgout)
                return;
            else
                dlgout=dlgout{1};
            end
            dlgout = string(dlgout).replace("end",num2str(app.img_cur.MetaData.slices));
            app.opt_crop.z = [min(str2num(dlgout)), max(str2num(dlgout))]; %#ok<ST2NM>
            if diff(app.opt_crop.z) < 2
                uialert(app.Reg3DV400daVinciUIFigure, ...
                    app.region.chmap.SLICE_FEW,...
                    app.region.chmap.WARNING, "Icon","warning");
                return;
            elseif diff(app.opt_crop.z) < 5
                slt = uiconfirm(app.Reg3DV400daVinciUIFigure,...
                    app.region.chmap.SLICES_FEW_FOR_NEXT_STEP, ...
                    app.region.chmap.WARNING, "Icon","warning");
                if slt ~= "OK"
                    return;
                end
            elseif diff(app.opt_crop.z) >= app.img_cur.MetaData.slices
                uialert(app.Reg3DV400daVinciUIFigure, ...
                    app.region.chmap.SLICES_OVERFLOW,...
                    app.region.chmap.WARNING, "Icon","warning");
                return;
            end

            PanelWaiting(app);  %

            % cropping two sources if it's possible
            if ~isempty(app.img_cur)
                app.img_cur = app.img_cur.vcrop("Z", app.opt_crop.z);
            end

            % !!! add history node
            [args, others] = app.TakeSystemBasicSnap();
            app.ohmgr.AddNode(constdef.OP_CROP, args, ...
                "Data",app.img_cur, "CropDim","Z", "Others",others);

            % update template definition
            app.refvol = regtmpl(app.img_cur, app.fixdef);

            % reorder slice axis
            app.sidx_cur = 1;
            SetSlider_Slice(app);

            % reset valid vpl
            app.opt_reg = set(app.opt_reg, "glVPL",  ...
                floor(log(app.img_cur.MetaData.slices)/log(4))+1);
            if isequal(app.opt_reg.Algorithm, "TCREG")
                app.opt_reg = set(app.opt_reg, "dfVPL",  ...
                    floor(log2(app.img_cur.MetaData.slices/0.7)));
                app.opt_reg = set(app.opt_reg, "dmVPL",  ...
                    floor(app.img_cur.MetaData.slices/2));
            end

            % Synchronize z projection
            SourceZProjection(app, [], app.mode_zproj);

            % Synchronize mROI mask
            if ~isempty(app.mask_reg)
                app.mask_reg = ...
                    app.mask_reg.crop("Z", app.opt_crop.z);
            end

            % Refresh RS handle
            if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                app.RS_handle.Refresh(app);
            end

            GenSysMsg(app, ["Z_CROPPED",""]);

            PanelRecovered(app);

            RefreshViewer(app, "Z");
        end

        % Menu selected function: Menu_LightContrast
        function Menu_LightContrastSelected(app, event)
            if isempty(app.BC_handle) || ~isvalid(app.BC_handle)
                if isMATLABReleaseOlderThan("R2023b")
                    app.BC_handle = BCAdjuster_old(app);
                else
                    app.BC_handle = BCAdjuster(app);
                end
            end
        end

        % Window button motion function: Reg3DV400daVinciUIFigure
        function Reg3DV400daVinciUIFigureWindowButtonMotion(app, event)
            % This callback offer realtime cursor position
            if ~isempty(app.viewer_locker) && ~app.viewer_locker && ...
                    ~isempty(app.mode_dview) && (app.mode_dview=="2D")
                % % ROI exist, hold on ROI information
                % if ~isempty(app.hdle_uROI) && isvalid(app.hdle_uROI)
                % end
                mouse_pos_cur = app.UIAxes_Slice.CurrentPoint(1,1:2);
                if any(mouse_pos_cur < 0) ...
                        || any(mouse_pos_cur>[app.img_cur.MetaData.width,...
                        app.img_cur.MetaData.height])
                    mouse_pos_cur = [];
                else
                    mouse_pos_cur = ceil(mouse_pos_cur);
                end

                if isempty(mouse_pos_cur)
                    % recover the none title
                    UpdateViewerTitle(app);
                    return;
                else
                    % readout pixel intensity from slice
                    x = mouse_pos_cur(1); y = mouse_pos_cur(2);
                    pixIxy = GetPixelValueAt(app, x, y);
                end

                UpdateViewerTitle(app,mouse_pos_cur,pixIxy);

                % update the screen utils components
                if ~isempty(app.text_time.ismoving) && app.text_time.ismoving
                    app.text_time.handle.Position = [mouse_pos_cur, 0];
                end
                if ~isempty(app.text_meta.ismoving) && app.text_meta.ismoving
                    app.text_meta.handle.Position = [mouse_pos_cur, 0];
                end
                if ~isempty(app.sbar.Moving) && app.sbar.Moving
                    app.sbar.move(mouse_pos_cur);
                end
            end
        end

        % Value changed function: Switch_Log
        function Switch_LogValueChanged(app, event)
            value = app.Switch_Log.Value;

            app.TextArea_Log.Enable = value;
        end

        % Menu selected function: Menu_RegEvaluate
        function Menu_RegEvaluateSelected(app, event)

            if isempty(app.EV_handle) || ~isvalid(app.EV_handle)
                app.EV_handle = Evaluator(app);
            end
        end

        % Menu selected function: Menu_SaveTimeAs
        function Menu_SaveTimeAsSelected(app, event)
            if ~isempty(app.img_cur.Time)
                if ~isempty(app.filename) && exist(app.filename,"file")
                    [~,fname,~] = fileparts(app.filename);
                else
                    fname = "processing";
                end

                [file, path] = uiputfile(...
                    {'*.tim', [app.region.chmap.TIME_SERIES_FILE.char(), ' (*.tim)'];...
                    '*.txt', [app.region.chmap.TEXT_FILE.char(),' (*.txt)'];...
                    '*.csv', ['CSV (',app.region.chmap.COMMA_SEPARATED.char(),') (*.csv)'];...
                    '*.xlsx', ['Excel ',app.region.chmap.WORKSHEET.char(),'(*.xlsx)'];...
                    '*.xls', ['Excel 97-2003 ',app.region.chmap.WORKSHEET.char(),'(*.xls)']},...
                    app.region.chmap.EXPORT_TIME,...
                    app.path_save + string(fname));

                if isequal(path, 0) || isequal(file, 0)
                    return;
                else
                    fname = fullfile(path, file);
                    [~,~,ext] = fileparts(fname);

                    if ext == ".tim"
                        writematrix(app.img_cur.Time, fname, "FileType","text");
                    else
                        writematrix(app.img_cur.Time, fname);
                    end
                    
                    app.path_save = path;
                    GenSysMsg(app, ["TIME_EXPORT_SUCCESS",""]);
                end
            else
                uialert(app.Reg3DV400daVinciUIFigure, ...
                    app.region.chmap.TIME_MISSING, ...
                    app.region.chmap.WARNING, "Icon","warning");
            end
        end

        % Menu selected function: Menu_ExportLog
        function Menu_ExportLogSelected(app, event)
            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            path = [pathToMLAPP, filesep, 'log'];
            if ~isfolder(path)
                mkdir(path);
            end
            
            ExportTextArea_Log(app, path);

            GenSysMsg(app, ["LOGFILE_EXPORT_TO", " "+path]);
        end

        % Menu selected function: Menu_ClearLog
        function Menu_ClearLogSelected(app, event)
            
            ClearTextArea_Log(app);

            % clear system message
            app.sysmsg = [];
        end

        % Value changed function: Button_Run
        function Button_RunValueChanged(app, event)
            value = app.Button_Run.Value;

            if value == true
                if any(app.opt_locked{1,:}) == true
                    RegisterGo(app);
                else
                    RegNotReadyWarn(app);
                end
            else
                % stop the registration
                switch app.kernel.State
                    case app.kernel.KERNEL_ON
                        % turn off the kernel
                        % change the button status
                        app.Button_Run.BackgroundColor = [1, 1, 0];
                        app.Button_Run.Text = app.region.chmap.BREAK;

                        % send the stop signal
                        app.kernel.stop();
                    case app.kernel.KERNEL_OFF

                    otherwise
                        app.Button_Run.Text = app.region.chmap.RUN;
                        app.Button_Run.BackgroundColor = [0.96,0.96,0.96];
                end
            end
        end

        % Menu selected function: Menu_TimeStamp
        function Menu_TimeStampSelected(app, event)
            % switch the checked status
            value = app.Menu_TimeStamp.Checked;

            if value == false
                % new text object on screen
                app.text_time.handle = text(app.UIAxes_Slice,1,10,0,"00:00:00.000",...
                    "FontSize",14,"FontName","Times New Roman","Color",[1,1,0],...
                    "HorizontalAlignment","left");
                time = seconds(app.img_cur.Time);
                time.Format = 'hh:mm:ss.SSS';
                app.text_time.text = string(time);
                app.text_time.handle.String = app.text_time.text(app.fidx_cur);
                app.text_time.ismoving = false;
                app.text_time.handle.ButtonDownFcn = ...
                    createCallbackFcn(app,@TimeText_ButtonDownFcn,true);
                app.Menu_TimeStamp.Checked = "on";
            else
                delete(app.text_time.handle);
                app.text_time.handle = [];
                app.text_time.ismoving = [];
                app.Menu_TimeStamp.Checked = "off";
            end
        end

        % Menu selected function: Menu_MetaMarker
        function Menu_MetaMarkerSelected(app, event)
            % switch the checked status
            value = app.Menu_MetaMarker.Checked;

            if value == false
                % load the meta data file
                metadata = LoadMetaData(app.img_cur.Time);
                if ~isempty(metadata)
                    % new text object on screen
                    app.text_meta.handle = text(app.UIAxes_Slice,app.img_cur.MetaData.width-50,10,0,"water",...
                        "FontSize",14,"FontName","Times New Roman","Color",[1,1,0],...
                        "HorizontalAlignment","right");
                    app.text_meta.text = metadata;
                    app.text_meta.ismoving = false;
                    app.text_meta.handle.ButtonDownFcn = ...
                        createCallbackFcn(app,@MetaText_ButtonDownFcn,true);
                    app.Menu_MetaMarker.Checked = "on";
                end
            else
                delete(app.text_meta.handle);
                app.text_meta.handle = [];
                app.text_meta.text = [];
                app.text_meta.ismoving = [];
                app.Menu_MetaMarker.Checked = "off";
            end
        end

        % Menu selected function: Menu_SaveMovieAs
        function Menu_SaveMovieAsSelected(app, event)
            app.ME_handle = MovieExporter(app);
            waitfor(app.ME_handle);

            if ~isempty(app.movie_config)
                if ~isempty(app.movie_config.movie_name)
                    fname = app.movie_config.movie_name;
                else
                    pathToMLAPP = fileparts(mfilename('fullpath'));
                    t = datetime('now','TimeZone','local','Format','yyMMdd');
                    fname = fullfile(pathToMLAPP, string(t)+".avi");
                end
                % lock the viewer controller and record status
                slider_slice_status = app.Slider_Slice.Enable;
                app.Slider_Slice.Enable = "off";
                app.Slider_Time.Enable = "off";
                key_mode_old =  app.mode_loop;
                app.mode_loop = "one-step";
                app.Menu_PlayMode.Enable = "off";
                frame_idx = app.fidx_cur;

                % set the video writer
                vw = VideoWriter(fname, app.movie_config.movie_type);

                switch app.movie_config.movie_type
                    case "Motion JPEG 2000"
                        vw.CompressionRatio = app.movie_config.compression_ratio;
                        vw.LosslessCompression = app.movie_config.is_nocompression;
                    otherwise
                        if app.movie_config.movie_type == "Motion JPEG AVI"...
                                || app.movie_config.movie_type == "MPEG-4"
                            vw.Quality = app.movie_config.quality;
                        elseif app.movie_config.movie_type == "Uncompressed AVI"
                            %
                        end
                end
                vw.FrameRate = app.movie_config.fps;

                % open the video file
                open(vw);

                app.SetProgressBar(0);
                GenSysMsg(app, ["EXPORTING_MOVIE",""]);

                % setup the capture screen
                ss = get(0).ScreenSize(3:4);
                ss_uifig = app.Reg3DV400daVinciUIFigure.Position(3:4);
                ex_fig = figure;
                setFigure(ex_fig,cat(2,[0,0],ss_uifig./ss));
                set(ex_fig,"Visible","off");   % hide the window

                for k = 1:app.img_cur.MetaData.frames
                    % reset the frame
                    app.fidx_cur = k;
                    RefreshViewer(app);

                    % clear capture figure and copy objects
                    clf(ex_fig);
                    copyobj(app.UIAxes_Slice, ex_fig);
                    ax = get(ex_fig,"CurrentAxes");
                    if ~isempty(app.hdle_ROI) && isvalid(app.hdle_ROI)
                        [rect,w,h] = ROI2Rectangle(app.hdle_ROI);
                        f = getframe(ax,[rect(1,1),rect(2,2),w,h]);
                    else
                        f = getframe(ax);
                    end

                    % resize the rgb
                    if app.movie_config.is_rescale
                        rgb = frame2im(f);
                        rgb = imresize(rgb,...
                            [app.movie_config.height, app.movie_config.width],...
                            app.movie_config.interp_method);
                        f = im2frame(rgb);
                    end

                    % write the frame
                    writeVideo(vw, f);

                    % update bar processing
                    app.SetProgressBar(k/app.img_cur.MetaData.frames);
                end

                % close the video file
                close(vw);
                % close the capture screen
                close(ex_fig);

                % unlock the viewer controller and recover key_mode
                app.Slider_Slice.Enable = slider_slice_status;
                app.fidx_cur = frame_idx;
                app.Slider_Time.Enable = "on";
                app.mode_loop = key_mode_old;
                app.Menu_PlayMode.Enable = "on";

                RefreshViewer(app);

                GenSysMsg(app, ["MOVIE_EXPORTED_SUCCESS",""]);
            end

        end

        % Menu selected function: Menu_RegRange
        function Menu_RegRangeSelected(app, event)
            prompt = {['\fontsize{12} ', app.region.chmap.TIME_RANGE.char()]};
            dlgtitle = app.region.chmap.UNREGISTRATERED_FRAMES.char();
            dims = [1 40];
            definput = {char(app.frames_reg(1))};
            dlgopts.WindowStyle = 'normal';
            dlgopts.Interpreter = "tex";
            dlgout = inputdlg(prompt,dlgtitle,dims,definput,dlgopts);
            if isempty(dlgout)
                return;
            else
                dlgout=dlgout{1};
            end

            % validate inputs updation
            rf = str2num(strrep(dlgout,'end', ...
                num2str(app.img_cur.MetaData.frames))); %#ok<ST2NM>
            if isempty(rf)
                uialert(app.Reg3DV400daVinciUIFigure, ...
                    app.region.chmap.INVALID_FRAME_ORDER, ...
                    app.region.chmap.WARNING, "Icon","warning");
            else
                if numel(rf) > app.img_cur.MetaData.frames
                    uialert(app.Reg3DV400daVinciUIFigure, ...
                        app.region.chmap.FRAME_OVERFLOW, ...
                        app.region.chmap.WARNING, "Icon","warning");
                else
                    app.frames_reg(1) = string(dlgout);
                end
            end
        end

        % Menu selected function: Menu_ScaleBar
        function Menu_ScaleBarSelected(app, event)
            app.sbar.Locked = true;

            % disable the view mode
            app.Menu_DisplayMode.Enable = "off";

            app.SB_handle = ScaleBar(app);
            % hold the handle until end setting
            uiwait(app.SB_handle.ScaleBarUIFigure);

            % enable the view mode after modifying
            app.Menu_DisplayMode.Enable = "on";

            app.sbar.Locked = false;
        end

        % Close request function: Reg3DV400daVinciUIFigure
        function Reg3DV400daVinciUIFigureCloseRequest(app, event)
            % finish the update setting
            is_update = app.opt_update.is_update;

            % free this app
            Free(app);

            % check the update option
            if is_update == true
                run(fullfile(userpath, "auto_update.m"));
                delete(fullfile(userpath, "auto_update.m"));
            end
        end

        % Menu selected function: Menu_RegOptions
        function Menu_RegOptionsSelected(app, event)
            % This function assigns different registration setting by
            % using channel(s) number
            if isempty(app.RS_handle) || ~isvalid(app.RS_handle)
                if isscalar(app.c_order)
                    % calling the one channel setting subapp
                    app.RS_handle = OCRegSetter(app);
                elseif numel(app.c_order) == 2
                    % calling the two channels setting subapp
                    app.RS_handle = TCRegSetter(app);
                else
                    app.RS_handle = [];
                    uialert(app.Reg3DV400daVinciUIFigure, ...
                       app.region.chmap.UNSUPPORTED_CHANNEL_NUMBER, ...
                        app.region.chmap.ERROR, "Icon","error");
                    return;
                end
            end
        end

        % Menu selected function: Menu_MaxZProjection
        function Menu_MaxZProjectionSelected(app, event)
            switch app.Menu_MaxZProjection.Checked
                case "on"
                    app.Menu_MaxZProjection.Checked = "off";
                    app.mode_zproj = "none";
                    SetSlider_Slice(app,'on');
                case "off"
                    app.Menu_MaxZProjection.Checked = "on";
                    app.Menu_MinZProjection.Checked = "off";
                    app.Menu_MedianZProjection.Checked = "off";
                    app.Menu_MeanZProjection.Checked = "off";

                    app.mode_zproj = "max";
                    SetSlider_Slice(app,'freeze');

                    PanelWaiting(app);
                    SourceZProjection(app,[],"max");
                    PanelRecovered(app);
                otherwise
            end

            RefreshViewer(app, "Z");
        end

        % Menu selected function: Menu_MinZProjection
        function Menu_MinZProjectionSelected(app, event)
            switch app.Menu_MinZProjection.Checked
                case "on"
                    app.Menu_MinZProjection.Checked = "off";
                    app.mode_zproj = "none";
                    SetSlider_Slice(app,'on');
                case "off"
                    app.Menu_MaxZProjection.Checked = "off";
                    app.Menu_MinZProjection.Checked = "on";
                    app.Menu_MedianZProjection.Checked = "off";
                    app.Menu_MeanZProjection.Checked = "off";

                    app.mode_zproj = "min";
                    SetSlider_Slice(app,'freeze');

                    PanelWaiting(app);
                    SourceZProjection(app,[],"min");
                    PanelRecovered(app);
                otherwise
            end

            RefreshViewer(app, "Z");
        end

        % Menu selected function: Menu_MedianZProjection
        function Menu_MedianZProjectionSelected(app, event)
            switch app.Menu_MedianZProjection.Checked
                case "on"
                    app.Menu_MedianZProjection.Checked = "off";
                    app.mode_zproj = "none";
                    SetSlider_Slice(app,'on');
                case "off"
                    app.Menu_MaxZProjection.Checked = "off";
                    app.Menu_MinZProjection.Checked = "off";
                    app.Menu_MedianZProjection.Checked = "on";
                    app.Menu_MeanZProjection.Checked = "off";

                    app.mode_zproj = "median";
                    SetSlider_Slice(app,'freeze');

                    PanelWaiting(app);
                    SourceZProjection(app,[],"median");
                    PanelRecovered(app);
                otherwise
            end

            RefreshViewer(app, "Z");
        end

        % Menu selected function: Menu_MeanZProjection
        function Menu_MeanZProjectionSelected(app, event)
            switch app.Menu_MeanZProjection.Checked
                case "on"
                    app.Menu_MeanZProjection.Checked = "off";
                    app.mode_zproj = "none";
                    SetSlider_Slice(app,'on');
                case "off"
                    app.Menu_MaxZProjection.Checked = "off";
                    app.Menu_MinZProjection.Checked = "off";
                    app.Menu_MedianZProjection.Checked = "off";
                    app.Menu_MeanZProjection.Checked = "on";

                    app.mode_zproj = "mean";
                    SetSlider_Slice(app,'freeze');

                    PanelWaiting(app);
                    SourceZProjection(app,[],"mean");
                    PanelRecovered(app);
                otherwise
            end

            RefreshViewer(app, "Z");
        end

        % Menu selected function: Menu_Quit
        function Menu_QuitSelected(app, event)
            
            Reg3DV400daVinciUIFigureCloseRequest(app, event);
        end

        % Menu selected function: Menu_ChannelAlignment
        function Menu_ChannelAlignmentSelected(app, event)
            if app.channel_locker == false
                app.channel_locker = true;
                app.Menu_ChannelAlignment.Checked = "on";
                % lock the player controller
                LockViewerController(app);
                % call subapp channel alignment
                if isempty(app.CC_handle) || ~isvalid(app.CC_handle)
                    app.CC_handle = ChannelCorrector(app);
                    waitfor(app.CC_handle);
                end
                UnlockViewerController(app);
                app.channel_locker = false;
                app.Menu_ChannelAlignment.Checked = "off";
                RefreshViewer(app);
            else
                delete(app.CC_handle);
                UnlockViewerController(app);
                app.channel_locker = false;
                app.Menu_ChannelAlignment.Checked = "off";
                RefreshViewer(app);
            end
        end

        % Window scroll wheel function: Reg3DV400daVinciUIFigure
        function Reg3DV400daVinciUIFigureWindowScrollWheel(app, event)
            vSC = event.VerticalScrollCount;

            % update the slice viewer
            if ~isempty(app.hdle_image) && isvalid(app.hdle_image)
                if (app.UIAxes_Slice.CurrentPoint(2,2) > app.scroll_ylim(1)) ...
                        && (app.UIAxes_Slice.CurrentPoint(2,2) < app.scroll_ylim(2))
                    dz = 0;
                    if vSC < 0
                        while dz > vSC, NextSlicePtr(app); dz=dz-1; end
                    else
                        while dz < vSC, PrevSlicePtr(app); dz=dz+1; end
                    end
                    RefreshViewer(app, "Z");
                end
            end
            
        end

        % Menu selected function: Menu_RunModalDistributed
        function Menu_RunModalDistributedSelected(app, event)
            app.Menu_RunModalDistributed.Checked = "on";
            app.Menu_RunModalExclusive.Checked = "off";
            app.distrib_flag = true;

            ChangeRunModal(app);
        end

        % Menu selected function: Menu_RunModalExclusive
        function Menu_RunModalExclusiveSelected(app, event)
            if app.flag_server
                slt = uiconfirm(app.Reg3DV400daVinciUIFigure, ...
                    app.region.chmap.EXCLUSIVE_WARNING, ...
                    app.region.chmap.WARNING, ...
                    "Icon","warning");
                if slt == "OK"
                    app.Menu_RunModalDistributed.Checked = "off";
                    app.Menu_RunModalExclusive.Checked = "on";
                    app.distrib_flag = false;
                else
                    return;
                end
            else
                avail_mem = GetAvailableMemory();
                tot_mem = app.img_cur.Bytes.map;
                if avail_mem > tot_mem * 2.5       % 2.5 as capacity coefficient
                    app.Menu_RunModalDistributed.Checked = "off";
                    app.Menu_RunModalExclusive.Checked = "on";
                    app.distrib_flag = false;
                else
                    uialert(app.Reg3DV400daVinciUIFigure, ...
                        app.region.chmap.LOW_MEMORY_WARNING, ...
                        app.region.chmap.WARNING, ...
                        "Icon","warning");
                end
            end

            ChangeRunModal(app);
        end

        % Size changed function: Panel_Viewer
        function Panel_ViewerSizeChanged(app, event)
            if (~isempty(app.hdle_image) && isvalid(app.hdle_image)) ...
                    || (~isempty(app.hdle_volume) && isvalid(app.hdle_volume))
                UpdateYScrollLim(app);
                ScalingProgressBar(app);
            end
        end

        % Image clicked function: Image_Update
        function Image_UpdateClicked(app, event)
            % Click the image for updating
            [~, url, ver] = acquire_update(app.opt_update.repository, Register.VER);
            [path, file, ext] = fileparts(url);

            % read the update information from remote host
            fileToUpdateTxt = [path, filesep, 'update ', 'V', ver, '.txt'];
            if isfile(fileToUpdateTxt)
                update_info = readlines(fileToUpdateTxt);
                % text completeness with higher priority
                if numel(update_info) > 9
                    slt = uiconfirm(app.Reg3DV400daVinciUIFigure, string(update_info), ...
                        app.region.chmap.INFORMATION, "Icon","info","Options",{'Update','Cancel'}, ...
                        "Interpreter","none");
                else    % uiconfirm bug: lost text if #strings > 7 on "tex" interpreter since R2021b
                    update_info = Register.ReformatUpdateText(update_info);
                    slt = uiconfirm(app.Reg3DV400daVinciUIFigure, string(update_info), ...
                        app.region.chmap.INFORMATION, "Icon","info","Options",{'Update','Cancel'}, ...
                        "Interpreter","tex");
                end
                if ~strcmp(slt, 'Update')
                    return;
                end
            end

            fileToMLAPP = [file, ext];
            bar = uiprogressdlg(app.Reg3DV400daVinciUIFigure,...
                "Icon","info", "Indeterminate","on",...
                "Message",app.region.chmap.DOWNLOADING);

            if ~exist(['downloads', filesep], "dir")
                mkdir(['downloads', filesep]);
            end
            copyfile(url, ['downloads', filesep, fileToMLAPP]);

            close(bar);

            % call outer script generator
            [~, att] = fileattrib(['downloads', filesep, fileToMLAPP]);

            status = reg3d_update(string(att.Name), app.opt_update.auto_clean);

            % set the update flag
            app.opt_update.is_update = status;

            if status == true
                uialert(app.Reg3DV400daVinciUIFigure,...
                    app.region.chmap.FINISH_UPDATE, ...
                    app.region.chmap.INFORMATION,"Icon","success");
            else
                uialert(app.Reg3DV400daVinciUIFigure,...
                    app.region.chmap.UPDATE_SCRIPT_FAILED, ...
                    app.region.chmap.ERROR, "Icon","error");
                delete(['downloads', filesep, fileToMLAPP]);
            end
        end

        % Button pushed function: Button_HistoryManager
        function Button_HistoryManagerPushed(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            if app.himgr_flag == true
                app.Reg3DV400daVinciUIFigure.Position(3) = app.LAYOUT_WIDTH_INIT;
                app.GridLayout_RegView.ColumnWidth = {'1x', 0};
                app.Image_HMIndicator.ImageSource = ...
                    fullfile(pathToMLAPP, 'sources', 'right_arrow_mini.png');
                app.himgr_flag = false;
            else
                app.Reg3DV400daVinciUIFigure.Position(3) = ...
                    app.LAYOUT_WIDTH_INIT + app.RIGHT_SIDEBAR_WIDTH;
                app.GridLayout_RegView.ColumnWidth = {'1x', app.RIGHT_SIDEBAR_WIDTH};
                app.Image_HMIndicator.ImageSource = ...
                    fullfile(pathToMLAPP, 'sources', 'left_arrow_mini.png');
                app.himgr_flag = true;
            end
        end

        % Menu selected function: Menu_SignalExtractor
        function Menu_SignalExtractorSelected(app, event)
            if isempty(app.SE_handle) || ~isvalid(app.SE_handle)
                app.SE_handle = SignalEstimator(app);
            end
        end

        % Menu selected function: Menu_AutoClearLog
        function Menu_AutoClearLogSelected(app, event)
            app.auto_clear_log_flag = ...
                ~app.auto_clear_log_flag;

            app.Menu_AutoClearLog.Checked = app.auto_clear_log_flag;
        end

        % Menu selected function: Menu_FlipHorizontal
        function Menu_FlipHorizontalSelected(app, event)
            app.Menu_FlipHorizontal.Checked = ~app.Menu_FlipHorizontal.Checked;

            PanelWaiting(app);

            VolumePostureAdjustment(app, "fliplr");

            PanelRecovered(app);
                
            RefreshViewer(app);
        end

        % Menu selected function: Menu_FlipVertical
        function Menu_FlipVerticalSelected(app, event)
            app.Menu_FlipVertical.Checked = ~app.Menu_FlipVertical.Checked;
            
            PanelWaiting(app);

            VolumePostureAdjustment(app, "flipud");

            PanelRecovered(app);

            RefreshViewer(app);
        end

        % Menu selected function: Menu_RotateClockwise90
        function Menu_RotateClockwise90Selected(app, event)
            PanelWaiting(app);

            VolumePostureAdjustment(app, "-rot90");

            PanelRecovered(app);

            RefreshViewer(app);
        end

        % Menu selected function: Menu_RotateCounterclockwise90
        function Menu_RotateCounterclockwise90Selected(app, event)
            PanelWaiting(app);

            VolumePostureAdjustment(app, "rot90");

            PanelRecovered(app);

            RefreshViewer(app);
        end

        % Menu selected function: Menu_View2D
        function Menu_View2DSelected(app, event)
            app.mode_dview = "2D";

            app.Menu_View2D.Checked = "on";
            app.Menu_View3D.Checked = "off";

            app.Panel_Viewer.Title = app.region.chmap.SLICE_VIEW;
            if app.mode_zproj == "none"
                SetSlider_Slice(app, 'on');
            end

            if app.viewer_locker == false
                RemoveViewer(app);
                AppendViewer(app);

                InitViewer(app);

                RefreshViewer(app);
            end

            UnlockComponents_2DView(app);
        end

        % Menu selected function: Menu_View3D
        function Menu_View3DSelected(app, event)
            if ~isempty(app.CS_handle) && isvalid(app.CS_handle)
                slt = uiconfirm(app.Reg3DV400daVinciUIFigure, ...
                    app.region.chmap.SERMENTATION_LOST_WARNING, ...
                    app.region.chmap.WARNING, "Icon","warning");
                if slt ~= "OK"
                    return;
                else
                    CloseSubApp(app, "CS");
                end
            end
            
            app.mode_dview = "3D";

            app.Menu_View2D.Checked = "off";
            app.Menu_View3D.Checked = "on";

            app.Panel_Viewer.Title = app.region.chmap.VOLUME_VIEW;
            SetSlider_Slice(app, 'freeze');

            if app.viewer_locker == false
                RemoveViewer(app);
                AppendViewer(app);

                InitViewer(app);

                RefreshViewer(app);
            end

            LockComponents_3DView(app);
        end

        % Menu selected function: Menu_CellSegmentor
        function Menu_CellSegmentorSelected(app, event)
            if isempty(app.CS_handle) || ~isvalid(app.CS_handle)
                app.CS_handle = CellSegmentor(app);
            end
        end

        % Menu selected function: Menu_15F
        function Menu_15FSelected(app, event)
            app.fps.fold = 15;
            app.player_timer.Period = round(1/(app.fps.base*app.fps.fold), 3);
            app.Menu_1F.Checked = "off";
            app.Menu_5F.Checked = "off";
            app.Menu_15F.Checked = "on";
        end

        % Menu selected function: Menu_LoadTime
        function Menu_LoadTimeSelected(app, event)
            if ~isempty(app.img_cur)    % correspond to current image
                % load time
                [file, path] = uigetfile(...
                    {'*.tim', [app.region.chmap.TIME_SERIES_FILE.char(), ' (*.tim)'];...
                    '*.txt', [app.region.chmap.TEXT_FILE.char(),' (*.txt)'];...
                    '*.csv', ['CSV (',app.region.chmap.COMMA_SEPARATED.char(),') (*.csv)'];...
                    '*.xlsx', ['Excel ',app.region.chmap.WORKSHEET.char(),'(*.xlsx)'];...
                    '*.xls', ['Excel 97-2003 ',app.region.chmap.WORKSHEET.char(),'(*.xls)']},...
                    app.region.chmap.LOAD_TIME, "camera_time.tim");

                [~, ~, ext] = fileparts(file);
                switch ext
                    case ".tim"
                        rt = readmatrix(fullfile(path, file), "FileType","text");
                    otherwise
                        rt = readmatrix(fullfile(path, file));
                end

                % set time
                app.img_cur.Time = rt;

                GenSysMsg(app, ["TIME_LOADING_COMPLETE", " "+fullfile(path, file)]);
            end
        end

        % Menu selected function: Menu_ResourceManager
        function Menu_ResourceManagerSelected(app, event)
            % This function calls resouerce manager gui
            if isempty(app.RM_handle) || ~isvalid(app.RM_handle)
                app.RM_handle = ResourceManager(app);
            end
        end

        % Menu selected function: Menu_DeleteNode
        function Menu_DeleteNodeSelected(app, event)
            % This callback will remove all data which stored in selected
            % branch after the node, note that if active node after the
            % deleted node, active node will be moved automatically

            % delete node
            app.ohmgr.DelNode(event.ContextObject);

            % restore system status
            nd = app.ohmgr.ActiveNodeData;
            app.RestoreSystemBy(nd);

        end

        % Menu selected function: Menu_ActivateNode
        function Menu_ActivateNodeSelected(app, event)
            % This function will mark the selected node as active

            % activate node
            app.ohmgr.ActivateNode(event.ContextObject);

            % restore system status
            nd = app.ohmgr.ActiveNodeData;
            app.RestoreSystemBy(nd);

        end

        % Menu selected function: Menu_PanelExpand
        function Menu_PanelExpandSelected(app, event)
            fprintf("Expand test!\n");
        end

        % Menu selected function: Menu_PanelCollapse
        function Menu_PanelCollapseSelected(app, event)
            fprintf("Collapse test!\n");
        end

        % Value changed function: DropDown_Source
        function DropDown_SourceValueChanged(app, event)
            value = app.DropDown_Source.Value;
            
            app.mode_merge = string(value);

            switch app.mode_merge
                case "NORMAL"
                    if ~isempty(app.Menu_MergeSource) && isvalid(app.Menu_MergeSource)
                        app.Menu_MergeSource.Enable = "off";
                    end
                    app.Menu_Red.Enable = "on";
                    app.Menu_Green.Enable = "on";
                    app.Menu_Red_Green.Enable = "on";
                case "FUSED"
                    if ~isempty(app.Menu_MergeSource) && isvalid(app.Menu_MergeSource)
                        app.Menu_MergeSource.Enable = "on";
                    end
                    app.Menu_Red.Enable = "on";
                    app.Menu_Green.Enable = "on";
                    app.Menu_Red_Green.Enable = "off";
                otherwise
            end

            RefreshViewer(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Reg3DV400daVinciUIFigure = uifigure('Visible', 'off');
            app.Reg3DV400daVinciUIFigure.Name = 'Reg3D V4.0.0 (da Vinci)';
            app.Reg3DV400daVinciUIFigure.Icon = fullfile(pathToMLAPP, 'sources', 'icon.png');
            app.Reg3DV400daVinciUIFigure.CloseRequestFcn = createCallbackFcn(app, @Reg3DV400daVinciUIFigureCloseRequest, true);
            app.Reg3DV400daVinciUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @Reg3DV400daVinciUIFigureWindowButtonMotion, true);
            app.Reg3DV400daVinciUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @Reg3DV400daVinciUIFigureWindowScrollWheel, true);
            app.Reg3DV400daVinciUIFigure.KeyPressFcn = createCallbackFcn(app, @Reg3DV400daVinciUIFigureKeyPress, true);
            app.Menu_File = uimenu(app.Reg3DV400daVinciUIFigure);
            app.Menu_File.Tooltip = {''};
            app.Menu_File.Text = 'Files';
            app.Menu_LoadImage = uimenu(app.Menu_File);
            app.Menu_LoadImage.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadImageSelected, true);
            app.Menu_LoadImage.Text = '加载图像';
            app.Menu_LoadTime = uimenu(app.Menu_File);
            app.Menu_LoadTime.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadTimeSelected, true);
            app.Menu_LoadTime.Text = '加载时间';
            app.Menu_SaveImageAs = uimenu(app.Menu_File);
            app.Menu_SaveImageAs.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveImageAsSelected, true);
            app.Menu_SaveImageAs.Separator = 'on';
            app.Menu_SaveImageAs.Text = '导出图像';
            app.Menu_SaveTimeAs = uimenu(app.Menu_File);
            app.Menu_SaveTimeAs.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveTimeAsSelected, true);
            app.Menu_SaveTimeAs.Text = '导出时间';
            app.Menu_SaveMovieAs = uimenu(app.Menu_File);
            app.Menu_SaveMovieAs.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveMovieAsSelected, true);
            app.Menu_SaveMovieAs.Text = '导出影片';
            app.Menu_Quit = uimenu(app.Menu_File);
            app.Menu_Quit.MenuSelectedFcn = createCallbackFcn(app, @Menu_QuitSelected, true);
            app.Menu_Quit.Separator = 'on';
            app.Menu_Quit.Text = '退出';
            app.Menu_Image = uimenu(app.Reg3DV400daVinciUIFigure);
            app.Menu_Image.Tooltip = {''};
            app.Menu_Image.Text = 'Images';
            app.Menu_LightContrast = uimenu(app.Menu_Image);
            app.Menu_LightContrast.MenuSelectedFcn = createCallbackFcn(app, @Menu_LightContrastSelected, true);
            app.Menu_LightContrast.Text = '亮度/对比度';
            app.Menu_ZProjection = uimenu(app.Menu_Image);
            app.Menu_ZProjection.Text = 'Z投影';
            app.Menu_MaxZProjection = uimenu(app.Menu_ZProjection);
            app.Menu_MaxZProjection.MenuSelectedFcn = createCallbackFcn(app, @Menu_MaxZProjectionSelected, true);
            app.Menu_MaxZProjection.Text = '最大值';
            app.Menu_MinZProjection = uimenu(app.Menu_ZProjection);
            app.Menu_MinZProjection.MenuSelectedFcn = createCallbackFcn(app, @Menu_MinZProjectionSelected, true);
            app.Menu_MinZProjection.Text = '最小值';
            app.Menu_MedianZProjection = uimenu(app.Menu_ZProjection);
            app.Menu_MedianZProjection.MenuSelectedFcn = createCallbackFcn(app, @Menu_MedianZProjectionSelected, true);
            app.Menu_MedianZProjection.Text = '中位值';
            app.Menu_MeanZProjection = uimenu(app.Menu_ZProjection);
            app.Menu_MeanZProjection.MenuSelectedFcn = createCallbackFcn(app, @Menu_MeanZProjectionSelected, true);
            app.Menu_MeanZProjection.Text = '平均值';
            app.Menu_ChannelAlignment = uimenu(app.Menu_Image);
            app.Menu_ChannelAlignment.MenuSelectedFcn = createCallbackFcn(app, @Menu_ChannelAlignmentSelected, true);
            app.Menu_ChannelAlignment.Text = '通道对齐';
            app.Menu_Comments = uimenu(app.Menu_Image);
            app.Menu_Comments.Text = '添加注释';
            app.Menu_TimeStamp = uimenu(app.Menu_Comments);
            app.Menu_TimeStamp.MenuSelectedFcn = createCallbackFcn(app, @Menu_TimeStampSelected, true);
            app.Menu_TimeStamp.Text = '时间戳';
            app.Menu_ScaleBar = uimenu(app.Menu_Comments);
            app.Menu_ScaleBar.MenuSelectedFcn = createCallbackFcn(app, @Menu_ScaleBarSelected, true);
            app.Menu_ScaleBar.Text = '比例 / 尺';
            app.Menu_MetaMarker = uimenu(app.Menu_Comments);
            app.Menu_MetaMarker.MenuSelectedFcn = createCallbackFcn(app, @Menu_MetaMarkerSelected, true);
            app.Menu_MetaMarker.Text = '元数据';
            app.Menu_Crop3D = uimenu(app.Menu_Image);
            app.Menu_Crop3D.Separator = 'on';
            app.Menu_Crop3D.Text = '体积裁剪';
            app.Menu_CropXY = uimenu(app.Menu_Crop3D);
            app.Menu_CropXY.MenuSelectedFcn = createCallbackFcn(app, @Menu_CropXYSelected, true);
            app.Menu_CropXY.Text = 'XY裁剪';
            app.Menu_CropZ = uimenu(app.Menu_Crop3D);
            app.Menu_CropZ.MenuSelectedFcn = createCallbackFcn(app, @Menu_CropZSelected, true);
            app.Menu_CropZ.Text = 'Z裁剪';
            app.Menu_CropT = uimenu(app.Menu_Image);
            app.Menu_CropT.MenuSelectedFcn = createCallbackFcn(app, @Menu_CropTSelected, true);
            app.Menu_CropT.Text = '帧裁剪';
            app.Menu_Flip = uimenu(app.Menu_Image);
            app.Menu_Flip.Separator = 'on';
            app.Menu_Flip.Text = '翻转';
            app.Menu_FlipHorizontal = uimenu(app.Menu_Flip);
            app.Menu_FlipHorizontal.MenuSelectedFcn = createCallbackFcn(app, @Menu_FlipHorizontalSelected, true);
            app.Menu_FlipHorizontal.Text = '水平';
            app.Menu_FlipVertical = uimenu(app.Menu_Flip);
            app.Menu_FlipVertical.MenuSelectedFcn = createCallbackFcn(app, @Menu_FlipVerticalSelected, true);
            app.Menu_FlipVertical.Text = '垂直';
            app.Menu_Rotate = uimenu(app.Menu_Image);
            app.Menu_Rotate.Text = '旋转';
            app.Menu_RotateClockwise90 = uimenu(app.Menu_Rotate);
            app.Menu_RotateClockwise90.MenuSelectedFcn = createCallbackFcn(app, @Menu_RotateClockwise90Selected, true);
            app.Menu_RotateClockwise90.Text = '顺时针90°';
            app.Menu_RotateCounterclockwise90 = uimenu(app.Menu_Rotate);
            app.Menu_RotateCounterclockwise90.MenuSelectedFcn = createCallbackFcn(app, @Menu_RotateCounterclockwise90Selected, true);
            app.Menu_RotateCounterclockwise90.Text = '逆时针90°';
            app.Menu_Options = uimenu(app.Reg3DV400daVinciUIFigure);
            app.Menu_Options.Tooltip = {''};
            app.Menu_Options.Text = 'Options';
            app.Menu_RunModal = uimenu(app.Menu_Options);
            app.Menu_RunModal.Text = '运行模态';
            app.Menu_RunModalDistributed = uimenu(app.Menu_RunModal);
            app.Menu_RunModalDistributed.MenuSelectedFcn = createCallbackFcn(app, @Menu_RunModalDistributedSelected, true);
            app.Menu_RunModalDistributed.Checked = 'on';
            app.Menu_RunModalDistributed.Text = '分布式';
            app.Menu_RunModalExclusive = uimenu(app.Menu_RunModal);
            app.Menu_RunModalExclusive.MenuSelectedFcn = createCallbackFcn(app, @Menu_RunModalExclusiveSelected, true);
            app.Menu_RunModalExclusive.Text = '独占';
            app.Menu_Register = uimenu(app.Menu_Options);
            app.Menu_Register.Text = '配准';
            app.Menu_RegOptions = uimenu(app.Menu_Register);
            app.Menu_RegOptions.MenuSelectedFcn = createCallbackFcn(app, @Menu_RegOptionsSelected, true);
            app.Menu_RegOptions.Text = '参数设置';
            app.Menu_RegRange = uimenu(app.Menu_Register);
            app.Menu_RegRange.MenuSelectedFcn = createCallbackFcn(app, @Menu_RegRangeSelected, true);
            app.Menu_RegRange.Text = '配准范围';
            app.Menu_RegEvaluate = uimenu(app.Menu_Register);
            app.Menu_RegEvaluate.MenuSelectedFcn = createCallbackFcn(app, @Menu_RegEvaluateSelected, true);
            app.Menu_RegEvaluate.Text = '结果评估';
            app.Menu_SaveParameters = uimenu(app.Menu_Register);
            app.Menu_SaveParameters.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveParametersSelected, true);
            app.Menu_SaveParameters.Separator = 'on';
            app.Menu_SaveParameters.Text = '导出预设';
            app.Menu_LoadParameters = uimenu(app.Menu_Register);
            app.Menu_LoadParameters.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadParametersSelected, true);
            app.Menu_LoadParameters.Text = '加载预设';
            app.Menu_Animation = uimenu(app.Menu_Options);
            app.Menu_Animation.Separator = 'on';
            app.Menu_Animation.Text = '动画';
            app.Menu_1F = uimenu(app.Menu_Animation);
            app.Menu_1F.MenuSelectedFcn = createCallbackFcn(app, @Menu_1FSelected, true);
            app.Menu_1F.Text = '1X';
            app.Menu_5F = uimenu(app.Menu_Animation);
            app.Menu_5F.MenuSelectedFcn = createCallbackFcn(app, @Menu_5FSelected, true);
            app.Menu_5F.Text = '5X';
            app.Menu_15F = uimenu(app.Menu_Animation);
            app.Menu_15F.MenuSelectedFcn = createCallbackFcn(app, @Menu_15FSelected, true);
            app.Menu_15F.Text = '15X';
            app.Menu_PlayMode = uimenu(app.Menu_Options);
            app.Menu_PlayMode.Text = '播放模式';
            app.Menu_Direct = uimenu(app.Menu_PlayMode);
            app.Menu_Direct.MenuSelectedFcn = createCallbackFcn(app, @Menu_DirectSelected, true);
            app.Menu_Direct.Text = '单向';
            app.Menu_Loop = uimenu(app.Menu_PlayMode);
            app.Menu_Loop.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoopSelected, true);
            app.Menu_Loop.Text = '循环';
            app.Menu_Advanced = uimenu(app.Reg3DV400daVinciUIFigure);
            app.Menu_Advanced.Text = 'Advanced';
            app.Menu_ResourceManager = uimenu(app.Menu_Advanced);
            app.Menu_ResourceManager.MenuSelectedFcn = createCallbackFcn(app, @Menu_ResourceManagerSelected, true);
            app.Menu_ResourceManager.Text = '资源管理';
            app.Menu_CellSegmentor = uimenu(app.Menu_Advanced);
            app.Menu_CellSegmentor.MenuSelectedFcn = createCallbackFcn(app, @Menu_CellSegmentorSelected, true);
            app.Menu_CellSegmentor.Text = '细胞分割';
            app.Menu_SignalExtractor = uimenu(app.Menu_Advanced);
            app.Menu_SignalExtractor.MenuSelectedFcn = createCallbackFcn(app, @Menu_SignalExtractorSelected, true);
            app.Menu_SignalExtractor.Text = '信号提取';
            app.Menu_Help = uimenu(app.Reg3DV400daVinciUIFigure);
            app.Menu_Help.Tooltip = {''};
            app.Menu_Help.Text = 'Help';
            app.Menu_About = uimenu(app.Menu_Help);
            app.Menu_About.MenuSelectedFcn = createCallbackFcn(app, @Menu_AboutSelected, true);
            app.Menu_About.Text = '关于';
            app.Menu_Documents = uimenu(app.Menu_Help);
            app.Menu_Documents.MenuSelectedFcn = createCallbackFcn(app, @Menu_DocumentsSelected, true);
            app.Menu_Documents.Text = '文档';
            app.GridLayout_RegView = uigridlayout(app.Reg3DV400daVinciUIFigure);
            app.GridLayout_HistoryManager = uigridlayout(app.GridLayout_RegView);
            app.Panel_OperationSnapshot = uipanel(app.GridLayout_HistoryManager);
            app.Panel_OperationSnapshot.Title = 'Operation Snapshot';
            app.GridLayout_OperationSnapshot = uigridlayout(app.Panel_OperationSnapshot);
            app.TextArea_OperationSnapshot = uitextarea(app.GridLayout_OperationSnapshot);
            app.TextArea_OperationSnapshot.Editable = 'off';
            app.Panel_OperationsViewer = uipanel(app.GridLayout_HistoryManager);
            app.Panel_OperationsViewer.Title = 'Operation View';
            app.GridLayout_OperationViewer = uigridlayout(app.Panel_OperationsViewer);
            app.Tree_Operations = uitree(app.GridLayout_OperationViewer);
            app.Tree_Operations.Tooltip = {''};
            app.Panel_StorageViewer = uipanel(app.GridLayout_HistoryManager);
            app.Panel_StorageViewer.Tooltip = {'存储浏览子组件'};
            app.Panel_StorageViewer.Title = 'Storage View';
            app.GridLayout_StorageViewer = uigridlayout(app.Panel_StorageViewer);
            app.GridLayout_RegBody = uigridlayout(app.GridLayout_RegView);
            app.Panel_Control = uipanel(app.GridLayout_RegBody);
            app.Panel_Control.Tooltip = {''};
            app.Panel_Control.Title = 'Control Panel';
            app.GridLayout2 = uigridlayout(app.Panel_Control);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Switch_Log = uiswitch(app.GridLayout3, 'rocker');
            app.Switch_Log.Items = {'off', 'on'};
            app.Switch_Log.ItemsData = {'off', 'on'};
            app.Switch_Log.ValueChangedFcn = createCallbackFcn(app, @Switch_LogValueChanged, true);
            app.Switch_Log.Tooltip = {''};
            app.Switch_Log.Value = 'on';
            app.TextArea_Log = uitextarea(app.GridLayout3);
            app.TextArea_Log.Editable = 'off';
            app.TextArea_Log.Placeholder = 'Welcome to Reg3D App';
            app.Label_20 = uilabel(app.GridLayout3);
            app.Label_20.Text = 'Log';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.Button_Run = uibutton(app.GridLayout4, 'state');
            app.Button_Run.ValueChangedFcn = createCallbackFcn(app, @Button_RunValueChanged, true);
            app.Button_Run.Tooltip = {''};
            app.Button_Run.Icon = fullfile(pathToMLAPP, 'sources', 'run.png');
            app.Button_Run.Text = 'Run';
            app.Label_19 = uilabel(app.GridLayout4);
            app.Label_19.Text = 'Source';
            app.DropDown_Source = uidropdown(app.GridLayout4);
            app.DropDown_Source.Items = {};
            app.DropDown_Source.ItemsData = {'NORMAL', 'FUSED'};
            app.DropDown_Source.ValueChangedFcn = createCallbackFcn(app, @DropDown_SourceValueChanged, true);
            app.DropDown_Source.Tooltip = {''};
            app.DropDown_Source.Value = {};
            app.Panel_Viewer = uipanel(app.GridLayout_RegBody);
            app.Panel_Viewer.Title = 'Slice View';
            app.Panel_Viewer.SizeChangedFcn = createCallbackFcn(app, @Panel_ViewerSizeChanged, true);
            app.GridLayout_ImageViewer = uigridlayout(app.Panel_Viewer);
            app.Label_2 = uilabel(app.GridLayout_ImageViewer);
            app.Label_2.Text = 'Slice';
            app.Slider_Slice = uislider(app.GridLayout_ImageViewer);
            app.Slider_Slice.Limits = [0 1];
            app.Slider_Slice.MajorTicks = [];
            app.Slider_Slice.ValueChangedFcn = createCallbackFcn(app, @Slider_SliceValueChanged, true);
            app.Slider_Slice.MinorTicks = [0 1];
            app.Slider_Slice.Tooltip = {''};
            app.GridLayout = uigridlayout(app.GridLayout_ImageViewer);
            app.Spinner_Time = uispinner(app.GridLayout);
            app.Spinner_Time.Limits = [1 Inf];
            app.Spinner_Time.ValueDisplayFormat = '%.0f';
            app.Spinner_Time.ValueChangedFcn = createCallbackFcn(app, @Spinner_TimeValueChanged, true);
            app.Spinner_Time.Tooltip = {''};
            app.Spinner_Time.Value = 1;
            app.Spinner_Slice = uispinner(app.GridLayout_ImageViewer);
            app.Spinner_Slice.Limits = [1 Inf];
            app.Spinner_Slice.ValueDisplayFormat = '%.0f';
            app.Spinner_Slice.ValueChangedFcn = createCallbackFcn(app, @Spinner_SliceValueChanged, true);
            app.Spinner_Slice.Tooltip = {''};
            app.Spinner_Slice.Value = 1;
            app.Image_Update = uiimage(app.GridLayout_ImageViewer);
            app.Image_Update.ImageClickedFcn = createCallbackFcn(app, @Image_UpdateClicked, true);
            app.Image_Update.Visible = 'off';
            app.Image_Update.ImageSource = fullfile(pathToMLAPP, 'sources', 'update_release.png');
            app.GridLayout_SidebarRegion = uigridlayout(app.GridLayout_ImageViewer);
            app.GridLayout6 = uigridlayout(app.GridLayout_SidebarRegion);
            app.Image_HMIndicator = uiimage(app.GridLayout6);
            app.Image_HMIndicator.Enable = 'off';
            app.Image_HMIndicator.ImageSource = fullfile(pathToMLAPP, 'sources', 'right_arrow_mini.png');
            app.Button_HistoryManager = uibutton(app.GridLayout_SidebarRegion, 'push');
            app.Button_HistoryManager.ButtonPushedFcn = createCallbackFcn(app, @Button_HistoryManagerPushed, true);
            app.Button_HistoryManager.Text = {'H'; 'i'; 's'; 't'; 'o'; 'r'; 'y'; ' '; 'M'; 'a'; 'n'; 'a'; 'g'; 'e'; 'r'};
            app.GridLayout_ViewBody = uigridlayout(app.GridLayout_ImageViewer);
            app.GridLayout_OuterPB = uigridlayout(app.GridLayout_ViewBody);
            app.GridLayout_ProgressBar = uigridlayout(app.GridLayout_OuterPB);
            app.Label_ProgressBar = uilabel(app.GridLayout_ProgressBar);
            app.Label_ProgressBar.Text = '';
            app.GridLayout_MainWin = uigridlayout(app.GridLayout_ViewBody);
            app.Label = uilabel(app.GridLayout_ImageViewer);
            app.Label.Text = 'Time(Frame)';
            app.Slider_Time = uislider(app.GridLayout_ImageViewer);
            app.Slider_Time.Limits = [0 1];
            app.Slider_Time.MajorTicks = [];
            app.Slider_Time.ValueChangedFcn = createCallbackFcn(app, @Slider_TimeValueChanged, true);
            app.Slider_Time.MinorTicks = [0 1];
            app.Slider_Time.Tooltip = {''};
            app.Label_ImageInfo = uilabel(app.GridLayout_ImageViewer);
            app.Label_ImageInfo.Visible = 'off';
            app.Label_ImageInfo.Text = 'Image Information';
            app.ContextMenu_Player = uicontextmenu(app.Reg3DV400daVinciUIFigure);
            app.Menu_ROI = uimenu(app.ContextMenu_Player);
            app.Menu_ROI.Text = '设置ROI';
            app.Menu_RectangleROI = uimenu(app.Menu_ROI);
            app.Menu_RectangleROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_RectangleROISelected, true);
            app.Menu_RectangleROI.Text = '矩形';
            app.Menu_CircleROI = uimenu(app.Menu_ROI);
            app.Menu_CircleROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_CircleROISelected, true);
            app.Menu_CircleROI.Text = '圆形';
            app.Menu_PolygonROI = uimenu(app.Menu_ROI);
            app.Menu_PolygonROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_PolygonROISelected, true);
            app.Menu_PolygonROI.Text = '多边形';
            app.Menu_FreehandROI = uimenu(app.Menu_ROI);
            app.Menu_FreehandROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_FreehandROISelected, true);
            app.Menu_FreehandROI.Text = '自由选择';
            app.Menu_Channel = uimenu(app.ContextMenu_Player);
            app.Menu_Channel.Text = '颜色通道';
            app.Menu_DisplayMode = uimenu(app.ContextMenu_Player);
            app.Menu_DisplayMode.Text = '显示模式';
            app.Menu_View2D = uimenu(app.Menu_DisplayMode);
            app.Menu_View2D.MenuSelectedFcn = createCallbackFcn(app, @Menu_View2DSelected, true);
            app.Menu_View2D.Checked = 'on';
            app.Menu_View2D.Text = '2D';
            app.Menu_View3D = uimenu(app.Menu_DisplayMode);
            app.Menu_View3D.MenuSelectedFcn = createCallbackFcn(app, @Menu_View3DSelected, true);
            app.Menu_View3D.Text = '3D';
            % Assign app.ContextMenu_Player
            app.GridLayout_ImageViewer.ContextMenu = app.ContextMenu_Player;
            app.GridLayout_MainWin.ContextMenu = app.ContextMenu_Player;
            app.ContextMenu_Controller = uicontextmenu(app.Reg3DV400daVinciUIFigure);
            app.Menu_ExportLog = uimenu(app.ContextMenu_Controller);
            app.Menu_ExportLog.MenuSelectedFcn = createCallbackFcn(app, @Menu_ExportLogSelected, true);
            app.Menu_ExportLog.Text = '导出';
            app.Menu_ClearLog = uimenu(app.ContextMenu_Controller);
            app.Menu_ClearLog.MenuSelectedFcn = createCallbackFcn(app, @Menu_ClearLogSelected, true);
            app.Menu_ClearLog.Text = '清空';
            app.Menu_AutoClearLog = uimenu(app.ContextMenu_Controller);
            app.Menu_AutoClearLog.MenuSelectedFcn = createCallbackFcn(app, @Menu_AutoClearLogSelected, true);
            app.Menu_AutoClearLog.Text = '自动清空';
            % Assign app.ContextMenu_Controller
            app.TextArea_Log.ContextMenu = app.ContextMenu_Controller;
            app.ContextMenu_Operation = uicontextmenu(app.Reg3DV400daVinciUIFigure);
            app.Menu_ActivateNode = uimenu(app.ContextMenu_Operation);
            app.Menu_ActivateNode.MenuSelectedFcn = createCallbackFcn(app, @Menu_ActivateNodeSelected, true);
            app.Menu_ActivateNode.Text = '激活节点';
            app.Menu_DeleteNode = uimenu(app.ContextMenu_Operation);
            app.Menu_DeleteNode.MenuSelectedFcn = createCallbackFcn(app, @Menu_DeleteNodeSelected, true);
            app.Menu_DeleteNode.Text = '删除节点';
            app.ContextMenu_PanelFolding = uicontextmenu(app.Reg3DV400daVinciUIFigure);
            app.Menu_PanelExpand = uimenu(app.ContextMenu_PanelFolding);
            app.Menu_PanelExpand.MenuSelectedFcn = createCallbackFcn(app, @Menu_PanelExpandSelected, true);
            app.Menu_PanelExpand.Text = '展开';
            app.Menu_PanelCollapse = uimenu(app.ContextMenu_PanelFolding);
            app.Menu_PanelCollapse.MenuSelectedFcn = createCallbackFcn(app, @Menu_PanelCollapseSelected, true);
            app.Menu_PanelCollapse.Text = '折叠';
            % Assign app.ContextMenu_PanelFolding
            app.GridLayout_StorageViewer.ContextMenu = app.ContextMenu_PanelFolding;
            % Show the figure after all components are created
            app.Reg3DV400daVinciUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Register

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Reg3DV400daVinciUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.Reg3DV400daVinciUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Reg3DV400daVinciUIFigure)
        end
    end
end